classdef RSFit3000_App_AIC_2_State < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RASGUI                   matlab.ui.Figure
        uitoolbar1               matlab.ui.container.Toolbar
        uitoggletool1            matlab.ui.container.toolbar.ToggleTool
        uitoggletool2            matlab.ui.container.toolbar.ToggleTool
        uitoggletool3            matlab.ui.container.toolbar.ToggleTool
        uitoggletool4            matlab.ui.container.toolbar.ToggleTool
        FittingOptions           matlab.ui.container.ButtonGroup
        StiffnessButton          matlab.ui.control.StateButton
        MuButton                 matlab.ui.control.StateButton
        WeightButton             matlab.ui.control.StateButton
        text67                   matlab.ui.control.Label
        LocNormStress            matlab.ui.control.EditField
        ClearFitLines            matlab.ui.control.Button
        ClearTestLines           matlab.ui.control.Button
        WeightPanel              matlab.ui.container.Panel
        WeightExponent           matlab.ui.control.EditField
        text65                   matlab.ui.control.Label
        WeightFrictionValue      matlab.ui.control.EditField
        WeightLocButton          matlab.ui.control.Button
        WeightSlipValue          matlab.ui.control.EditField
        text61                   matlab.ui.control.Label
        text60                   matlab.ui.control.Label
        EventButtonGroup         matlab.ui.container.ButtonGroup
        VelocityStepButton       matlab.ui.control.RadioButton
        SHSButton                matlab.ui.control.RadioButton
        UpdateListboxButton      matlab.ui.control.Button
        ListBox                  matlab.ui.control.ListBox
        SHSPanel                 matlab.ui.container.Panel
        text46                   matlab.ui.control.Label
        HoldTime                 matlab.ui.control.EditField
        HoldFrictionValue        matlab.ui.control.EditField
        LoadVelocityValue        matlab.ui.control.EditField
        text42                   matlab.ui.control.Label
        ReloadVelocityValue      matlab.ui.control.EditField
        text43                   matlab.ui.control.Label
        SetHoldButton            matlab.ui.control.Button
        HoldSlipValue            matlab.ui.control.EditField
        text44                   matlab.ui.control.Label
        text45                   matlab.ui.control.Label
        FittingPanel             matlab.ui.container.Panel
        CreateStructureButton    matlab.ui.control.Button
        FitTag                   matlab.ui.control.Label
        FitTagName               matlab.ui.control.EditField
        SaveWorkspaceButton      matlab.ui.control.Button
        text54                   matlab.ui.control.Label
        WorkspaceName            matlab.ui.control.EditField
        FitButton                matlab.ui.control.Button
        TestButton               matlab.ui.control.Button
        SlipAIC_2                matlab.ui.control.EditField
        SlipFlag_2               matlab.ui.control.EditField
        SlipRes_2                matlab.ui.control.EditField
        SlipFit_dc2Value_2       matlab.ui.control.EditField
        SlipFit_b2Value_2        matlab.ui.control.EditField
        SlipFit_kValue_2         matlab.ui.control.EditField
        SlipFit_dcValue_2        matlab.ui.control.EditField
        SlipFit_bValue_2         matlab.ui.control.EditField
        SlipFit_aValue_2         matlab.ui.control.EditField
        SlipFit_muValue_2        matlab.ui.control.EditField
        AgingAIC_2               matlab.ui.control.EditField
        AgingFlag_2              matlab.ui.control.EditField
        AgingRes_2               matlab.ui.control.EditField
        AgingFit_dc2Value_2      matlab.ui.control.EditField
        AgingFit_b2Value_2       matlab.ui.control.EditField
        AgingFit_kValue_2        matlab.ui.control.EditField
        AgingFit_dcValue_2       matlab.ui.control.EditField
        AgingFit_bValue_2        matlab.ui.control.EditField
        AgingFit_aValue_2        matlab.ui.control.EditField
        AgingFit_muValue_2       matlab.ui.control.EditField
        SlipAIC                  matlab.ui.control.EditField
        AgingAIC                 matlab.ui.control.EditField
        AICLabel                 matlab.ui.control.Label
        SlipButton               matlab.ui.control.StateButton
        AgingButton              matlab.ui.control.StateButton
        text68                   matlab.ui.control.Label
        SlipFlag                 matlab.ui.control.EditField
        SlipRes                  matlab.ui.control.EditField
        AgingFlag                matlab.ui.control.EditField
        AgingRes                 matlab.ui.control.EditField
        text53                   matlab.ui.control.Label
        Guess_dc2Value           matlab.ui.control.EditField
        text52                   matlab.ui.control.Label
        Guess_b2Value            matlab.ui.control.EditField
        SlipFit_kValue           matlab.ui.control.EditField
        SlipFit_dcValue          matlab.ui.control.EditField
        SlipFit_bValue           matlab.ui.control.EditField
        SlipFit_aValue           matlab.ui.control.EditField
        SlipFit_muValue          matlab.ui.control.EditField
        Fit_k                    matlab.ui.control.Label
        AgingFit_kValue          matlab.ui.control.EditField
        Fit_dc                   matlab.ui.control.Label
        AgingFit_dcValue         matlab.ui.control.EditField
        Fit_b                    matlab.ui.control.Label
        AgingFit_bValue          matlab.ui.control.EditField
        Fit_a                    matlab.ui.control.Label
        AgingFit_aValue          matlab.ui.control.EditField
        Fit_mu                   matlab.ui.control.Label
        AgingFit_muValue         matlab.ui.control.EditField
        Guess_kValue             matlab.ui.control.EditField
        Guess_dcValue            matlab.ui.control.EditField
        Guess_bValue             matlab.ui.control.EditField
        Guess_aValue             matlab.ui.control.EditField
        Guess_muValue            matlab.ui.control.EditField
        ParameterGuesses         matlab.ui.control.Label
        VelocityStepPanel        matlab.ui.container.Panel
        text41                   matlab.ui.control.Label
        text40                   matlab.ui.control.Label
        StepSlipValue            matlab.ui.control.EditField
        SetVelocityStepButton    matlab.ui.control.Button
        FinalVelocity            matlab.ui.control.Label
        FinalVelocityValue       matlab.ui.control.EditField
        text8                    matlab.ui.control.Label
        InitialVelocityValue     matlab.ui.control.EditField
        StepFrictionValue        matlab.ui.control.EditField
        DataPanel                matlab.ui.container.Panel
        text59                   matlab.ui.control.Label
        P_cDataName              matlab.ui.control.EditField
        TimeData                 matlab.ui.control.Label
        TimeDataName             matlab.ui.control.EditField
        FrictionData             matlab.ui.control.Label
        SlipData                 matlab.ui.control.Label
        FrictionDataName         matlab.ui.control.EditField
        SlipDataName             matlab.ui.control.EditField
        PlotButton               matlab.ui.control.Button
        DetrendPanel             matlab.ui.container.Panel
        ClearPointsButton        matlab.ui.control.Button
        SetReferencePointButton  matlab.ui.control.Button
        text64                   matlab.ui.control.Label
        text63                   matlab.ui.control.Label
        ReferencePointMuValue    matlab.ui.control.EditField
        ReferencePointSlipValue  matlab.ui.control.EditField
        text55                   matlab.ui.control.Label
        SetPoint2Button          matlab.ui.control.Button
        text50                   matlab.ui.control.Label
        text49                   matlab.ui.control.Label
        text48                   matlab.ui.control.Label
        text47                   matlab.ui.control.Label
        FitTrendButton           matlab.ui.control.Button
        SetPoint1Button          matlab.ui.control.Button
        Point2MuValue            matlab.ui.control.EditField
        Point1MuValue            matlab.ui.control.EditField
        Point2SlipValue          matlab.ui.control.EditField
        latex_Slope              matlab.ui.control.Label
        SlopeValue               matlab.ui.control.EditField
        DetrendButton            matlab.ui.control.Button
        Point1SlipValue          matlab.ui.control.EditField
        MainAxes                 matlab.ui.control.UIAxes
        LogoAxes                 matlab.ui.control.UIAxes
        StaticAxes               matlab.ui.control.UIAxes
        FittingAxes              matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function AgingFit_alphaValue_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to AgingFit_alphaValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function [x_0, x_e, v_i, v_f, NormStress, TimeOfStep, Slip_ZeroRef, Time_ZeroRef, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, Weight, Holds] = GetStuff(app, handles)
            
            %%% function to get parameter guesses and run flags, set inputs
    
        %%% Get parameter guesses, time of step, and step velocity values.
        %%% RAS fitting parameters.    
            mu_iString = get(app.Guess_muValue,'Value');
            mu_i = str2double(mu_iString);
            aString = get(app.Guess_aValue,'Value');
            a = str2double(aString);
            bString = get(app.Guess_bValue,'Value');
            b = str2double(bString);
            d_cString = get(app.Guess_dcValue,'Value');
            d_c = str2double(d_cString);
            kString = get(app.Guess_kValue,'Value');
            k = str2double(kString);
            
        %%% Check if constraining mu_i and stiffness. MuFlag = 1, fits mu_i. 
        %%% MuFlag = 0, does not fit mu_i. StiffnessFlag, same conditions as for 
        %%% mu_i.
            StiffnessFlag = getappdata(app.FitButton, 'StiffnessFlag');
            MuFlag = getappdata(app.FitButton, 'MuFlag');
            if StiffnessFlag == true && MuFlag == true
                x_0 = [mu_i; a; b; d_c; k];
                x_e = [0; 0];
            elseif StiffnessFlag == true && MuFlag == false
                x_0 = [nan; a; b; d_c; k];
                x_e = [mu_i; 0];
            elseif StiffnessFlag == false && MuFlag == true
                x_0 = [mu_i; a; b; d_c; nan];
                x_e = [0; k];
            else
                x_0 = [nan; a; b; d_c; nan];
                x_e = [mu_i; k];
            end
        
        %%% Check if using two state variables.
            StateVarFlag = getappdata(app.FitButton, 'StateVarFlag');
            if StateVarFlag
                b2String = get(app.Guess_b2Value,'Value');
                b2 = str2double(b2String);
                d_c2String = get(app.Guess_dc2Value,'Value');
                d_c2 = str2double(d_c2String);
            else
                b2 = 0;
                d_c2 = 0;
            end 
            x_0 = [x_0; b2; d_c2];
        
        %%% Check if fitting a velocity step or a slide-hold-slide.
            EventFlag = getappdata(app.FitButton, 'EventFlag');
            if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
        %%% Velocity step values.
                InitialVel_String = get(app.InitialVelocityValue,'Value');
                v_i = str2double(InitialVel_String);
                FinalVel_String = get(app.FinalVelocityValue,'Value');
                v_f = str2double(FinalVel_String);
        %%% Get time of velocity step by finding the location of the step in the
        %%% slip data and pulling out the corresponding time.
                slip_step = get(app.StepSlipValue,'Value');
                SlipStep = str2double(slip_step);
                Slip_ZeroRef = getappdata(app.DetrendButton,'SlipZeroRef_Data');
                Time_ZeroRef = getappdata(app.DetrendButton,'TimeZeroRef_Data');
                [~, ss] = min(abs(Slip_ZeroRef - SlipStep));
                TimeOfStep = Time_ZeroRef(ss);
                Holds{1} = [];
        %%% Get the normal stress at the time of the step.
                NormStressZoom = getappdata(app.SlopeValue, 'NormStressZoomData');
                NormStress = NormStressZoom(ss);
            else
                Slip_ZeroRef = getappdata(app.DetrendButton,'SlipZeroRef_Data');
                Time_ZeroRef = getappdata(app.DetrendButton,'TimeZeroRef_Data');
        %%% Slide-hold-slide values. Load, reload velocities.
                LoadVel_String = get(app.LoadVelocityValue,'Value');
                v_i = str2double(LoadVel_String);
                ReloadVel_String = get(app.ReloadVelocityValue,'Value');
                v_f = str2double(ReloadVel_String);
        %%% Length of hold.
                HoldTime_String = get(app.HoldTime,'Value');
                HoldTime = str2double(HoldTime_String);
        %%% Time when the hold initiates.
                TimeOfHold = getappdata(app.SetHoldButton,'Step_TimeValue');
                Holds{1} = HoldTime;
                Holds{2} = TimeOfHold;
        %%% Get the normal stress at the time of the hold.
                NormStress_String = get(app.LocNormStress,'Value');
                NormStress = str2double(NormStress_String);
        %%% Give empty set for TimeOfStep
                TimeOfStep = [];
            end
            
        %%% Construct weight function.
            WeightFlag = getappdata(app.FitButton, 'WeightFlag');    
            if WeightFlag    
                [Weight, ~] = WeightCalc(handles);
            else
                N = numel(Slip_ZeroRef);
                Weight = ones(1, N);
                setappdata(app.WeightLocButton, 'Weight_Function', Weight);
                setappdata(app.WeightLocButton, 'Weight_Info', nan(3,1));
            end
        end
        
        function Guess_alphaValue_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Guess_alphaValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function MainAxPostCallback(app, obj, event_obj)
            %%% Get x- and y-limits of new zoomed area and plot a red box on the static
            %%% axes.
            if isempty(event_obj.Axes.ButtonDownFcn) == 0
                XLimZoom = event_obj.Axes.XLim;
                YLimZoom = event_obj.Axes.YLim;
                app = guidata(obj);
            %%% See if there's a previous box plotted and delete it.
                children = get(app.StaticAxes, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Plot outline box on static axes when limits of main axes are changed.
                hold(app.StaticAxes, 'on');
                plot(app.StaticAxes,XLimZoom,YLimZoom(1)*[1 1],'r','LineWidth',2);
                plot(app.StaticAxes,XLimZoom,YLimZoom(2)*[1 1],'r','LineWidth',2);
                plot(app.StaticAxes,XLimZoom(1)*[1 1],YLimZoom,'r','LineWidth',2);
                plot(app.StaticAxes,XLimZoom(2)*[1 1],YLimZoom,'r','LineWidth',2);
                hold(app.StaticAxes, 'off');
            end
        end
        
        function Point2Values_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to Point2Values (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function [x_0, x_f, State1_f, State2_f, Vel_f, Mu_f, Slip_f, SampleSlip_f, exitflag, ODE_Sol, residual, jacobian, resnorm] = RASFittingLD1(app, x_0, x_e, v_i, v_f, NormStress, TimeOfStep, Mu_Data, LoadPointSlip, Exp_Time, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, Weight, SampleSlipFlag, handles, varargin)
            
            %%% Description of option flags.
            %%%     StateLawFlag = true : use the aging law
            %%%     StateLawFlag = false : use the slip law
            %%%     StateVarFlag = true :  use two state variables
            %%%     StateVarFlag = false :  use ones state variable
            %%%     EventFlag = true : fitting a velocity step
            %%%     EventFlag = false : fitting a slide-hold-slide
            
            %%% Contents of parameter array 'x_0'
            %%%     x(1) = mu_0
            %%%     x(2) = a;
            %%%     x(3) = b1;
            %%%     x(4) = d_c1;
            %%%     x(5) = k;
            %%%     x(6) = b2;
            %%%     x(7) = d_c2;
            %%%     x(8) = alpha;
            
            %%% Time steps used for performing fitting simulations and optimization.
                tSpan = linspace(0,Exp_Time(end),1e3)';
            %%% Optimize using nonlinear least squares fitting.
            %%% Experimental data that we are fitting.
                Data = [Mu_Data'; LoadPointSlip'];
            
            %%% Set up objective function where the actual least squares minimization
            %%% occurs, see below for description.
            %%% Check if a hold time is provided.
            if numel(varargin) == 1
                HoldTime = varargin{1};
                myObjective = @(x) objFcn(app, x, x_e, Exp_Time, Data, tSpan, v_i, v_f, NormStress,...
                    TimeOfStep, StateLawFlag, StateVarFlag, EventFlag,...
                    StiffnessFlag, MuFlag, Weight, SampleSlipFlag, HoldTime);
            else
                myObjective = @(x) objFcn(app, x, x_e, Exp_Time, Data, tSpan, v_i, v_f, NormStress,...
                    TimeOfStep, StateLawFlag, StateVarFlag, EventFlag,...
                    StiffnessFlag, MuFlag, Weight, SampleSlipFlag);
            end
            %%% Options for lsqnonlin.
            options = optimoptions(@lsqnonlin,'Algorithm',...
                'levenberg-marquardt');
            % options = optimoptions(@lsqnonlin,'Display','iter-detailed','Algorithm',...
                % 'levenberg-marquardt');
            %%% Carry out the optimization.
            [x_f, resnorm, residual, exitflag, ~, ~, jacobian] = ...
                    lsqnonlin(myObjective, x_0, [], [], options);
            
            %%% Run a spring-slider simulation using the fitted parameters contained in
            %%% x_f. This will produce numerical friction and slip data that can be
            %%% plotted over the experimental data.
            a = x_f(2);
            b1 = x_f(3);
            d_c1 = x_f(4);
            b2 = x_f(6);
            d_c2 = x_f(7);
            if MuFlag == true
                mu_0 = x_f(1);
            else
                mu_0 = x_e(1);
            end
            
            state1_i = d_c1/v_i;
            if StateVarFlag
                state2_i = d_c2/v_i;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                    + b2*log(v_i*state2_i/d_c2);
            else
                state2_i = 0;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
            end
            sigma_i = NormStress;
            tau_i = mu_i*sigma_i;
            slip_i = LoadPointSlip(1);
            Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            opt = odeset('RelTol',1e-9,'AbsTol',[1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_f,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                    tSpan,Vars_i,opt);
            else
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_f,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,...
                    HoldTime), tSpan,Vars_i,opt);
            end
            
            %%% Evaluate the ode solution for the experimental time steps.
            bestY = deval(ODE_Sol, Exp_Time);
            State1_f = bestY(1,:)';
            State2_f = bestY(2,:)';
            Vel_f = bestY(3,:)';
            Mu_f = bestY(4,:)';
            SampleSlip_f = bestY(5,:)';
            
            %%% Compute the load point slip
            Slip_f = nan(numel(Exp_Time), 1);
            [~, kk1] = min(abs(Exp_Time - TimeOfStep));
            Slip_f(1:kk1) = v_i*Exp_Time(1:kk1);
            if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Exp_Time(kk1:end)));
            else
                [~, kk2] = min(abs(Exp_Time - (TimeOfStep + HoldTime)));
                Slip_f(kk1+1:kk2) = Slip_f(kk1);
                Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Exp_Time(kk2:end)));
            end
        end
        
        function SampleSlipButton_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in SampleSlipButton.
            
            % hObject    handle to SampleSlipButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            if (get(hObject,'Value') == get(hObject,'Max'))
            %%% Button is selected, enable the sample slip data field, and set the
            %%% SampleSlipFlag to "true".
                setappdata(app.FitButton, 'SampleSlipFlag', true);
                set(app.SampleSlipDataName, 'Enable', 'on');
            else
            %%% Button is not selected, disable the sample slip data field, and set the
            %%% SampleSlipFlag to "false".
                setappdata(app.FitButton, 'SampleSlipFlag', false);
                set(app.SampleSlipDataName, 'Enable', 'off');
            end
        end
        
        function SampleSlipDataName(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to SampleSlipDataName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function SlipFit_alphaValue_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to SlipFit_alphaValue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function dVarsdt = SpringBlock(app, time, Vars, x, x_e, v_i, v_f, TimeOfStep, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, varargin)
            %%% This function contains the governing equations for a spring slider with
            %%% stiffness k. They are cast as coupled ODEs in state variable, friction
            %%% coefficient, and slider velocity. There is a fourth ode for slip that
            %%% integrates the velocity values.
            
            %%% RAS parameters.
                a = x(2);
                b1 = x(3);
                d_c1 = x(4);
                b2 = x(6);
                d_c2 = x(7);
                if StiffnessFlag == true
                    k = x(5);
                else
                    k = x_e(2);
                end
            
            %%% Simulation variables.
                state1 = Vars(1,1);
                state2 = Vars(2,1);
                vel = Vars(3,1);
                mu = Vars(4,1);
                sigma = Vars(6,1);
            
            %%% Check if a hold time is provided.
                if numel(varargin) == 1
                    HoldTime = varargin{1};
                end
            
            %%% Determine which type of event to fit.
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
            %%% Fit a velcity step.
                    if time < TimeOfStep
                        v_L = v_i;
                    else
                        v_L = v_f;
                    end
                else
            %%% Fit a slide-hold-slide
                    if time < TimeOfStep
                        v_L = v_i;
                    elseif time >= TimeOfStep & time < TimeOfStep + HoldTime
                        v_L = 0;
                    else
                        v_L = v_f;
                    end
                end
            
            %%% Friction stress.
                dmudt = k*(v_L - vel);
            %%% Shear stress
                dtaudt = dmudt*sigma;
            %%% Normal stress
                dsigmadt = 0;
            
            %%% State evolution.
                if StateLawFlag
            %%% Aging Law
                    dstate1dt = 1 - state1.*vel./d_c1;
                    if StateVarFlag
                        dstate2dt = 1 - state2.*vel./d_c2;
                    else
                        dstate2dt = 0;
                    end
                else
            %%% Slip Law
                    dstate1dt = -(vel.*state1/d_c1).*log(vel.*state1./d_c1);
                    if StateVarFlag
                        dstate2dt = -(vel.*state2/d_c2).*log(vel.*state2./d_c2);
                    else
                        dstate2dt = 0;
                    end
                end
            
            %%% Velocity
                if StateVarFlag
                    dveldt = (vel/a).*(dmudt - b1.*dstate1dt./state1...
                        - b2.*dstate2dt./state2);
                else
                    dveldt = (vel/a).*(dmudt - b1.*dstate1dt./state1);
                end
            %%% Sample Displacement
                dslipdt = vel;
            
            %%% Create ode vector.
                dVarsdt = [dstate1dt; dstate2dt; dveldt; dmudt; dslipdt; dsigmadt;...
                    dtaudt];
        end
        
        function StaticMuMax_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to StaticMuMax (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function StaticMuMin_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to StaticMuMin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    empty - app not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc & isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function [Weight, WeightInfo] = WeightCalc(app, handles)
                Slip_ZeroRef = getappdata(app.DetrendButton,'SlipZeroRef_Data');
                N = numel(Slip_ZeroRef);
                Weight = ones(1, N);
                slip_weight = get(app.WeightSlipValue, 'Value');
                SlipWeight = str2double(slip_weight);
                [~, i_w] = min(abs(Slip_ZeroRef - SlipWeight));
            %%% Calculate weight function.
                p_String = get(app.WeightExponent,'Value');
                p = str2double(p_String);
                Weight(1, i_w:end) = (N./(1:N-i_w+1)).^p;
            %%% Information for weight function.
                WeightInfo = [N; i_w; p];
            %%% Store
                setappdata(app.WeightLocButton, 'Weight_Function', Weight);
                setappdata(app.WeightLocButton, 'Weight_Info', [N; i_w; p]);
        end
        
        function FitError = objFcn(app, x, x_e, ExpTime, Data, time, v_i, v_f, NormStress, TimeOfStep, StateLawFlag, StateVarFlag, EventFlag, StiffnessFlag, MuFlag, Weight, SampleSlipFlag, varargin)
            %%% This is the optimization function that is used to determine the best
            %%% fit values for the RAS parameters contained in the vector x. It runs a
            %%% spring-slider simulation using the values in x, and attempts to
            %%% minimize the difference between the simulated friction coefficient and
            %%% slip values and the exerimental data.
                a = x(2);
                b1 = x(3);
                d_c1 = x(4);
                b2 = x(6);
                d_c2 = x(7);
                if StiffnessFlag == true & MuFlag == true
                    mu_0 = x(1);
                    k = x(5);
                elseif StiffnessFlag == true & MuFlag == false
                    mu_0 = x_e(1);
                    k = x(5);
                elseif StiffnessFlag == false & MuFlag == true
                    mu_0 = x(1);
                    k = x_e(2);
                else
                    mu_0 = x_e(1);
                    k = x_e(2);
                end
            
                  %disp(['mu_0 = ',num2str(mu_0),' ','a = ',num2str(a),' ','b = ',...
                  %    num2str(b1),' ','d_c = ',num2str(d_c1),' ','k = ',num2str(k)])
            
            %%% Initial, steady-state conditions for the section of the experiment
            %%% being analyzed.
                state1_i = d_c1/v_i;
                if StateVarFlag
                    state2_i = d_c2/v_i;
                    mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                        + b2*log(v_i*state2_i/d_c2);
                else
                    state2_i = 0;
                    mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
                end
                LoadPointSlip = Data(2,:);
                slip_i = LoadPointSlip(1);
                sigma_i = NormStress;
                tau_i = mu_i*sigma_i;
                Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            %%% Run the spring-slider simulation.
                opt = odeset('RelTol',1e-9,'AbsTol',...
                    [1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            %%% Check if a hold time is provided.
                if numel(varargin) == 1
                    HoldTime = varargin{1};
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x,x_e,v_i,v_f,...
                        TimeOfStep,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,HoldTime), time,Vars_i,opt);
                else
                    ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x,x_e,v_i,v_f,...
                        TimeOfStep,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag), time,Vars_i,opt);
                end
                if ODE_Sol.x(end) < ExpTime(end)
                    if b2 == 0
                        ME = MException('ODE:fail',...
                            ['ODE solver has failed at parameter values:' char(10) 'mu_0 = %.4f, a = %f, b_1 = %f, d_c1 = %.2f, k = %f'],...
                            mu_0, a, b1, d_c1, k);
                    else
                        ME = MException('ODE:fail',...
                            ['ODE solver has failed at parameter values:' char(10) 'mu_0 = %.4f, a = %f, b_1 = %f, b_2 = %f, d_c1 = %.2f, d_c2 = %.2f, k = %f'],...
                            mu_0, a, b1, b2, d_c1, d_c2, k);
                    end
                    throw(ME)
            %%% Evaluate the solution structure at the experimental time values.
                else
                    simY = deval(ODE_Sol, ExpTime);
                end
            
            %%% Fit based on minimizing friction coefficient.
                FitError = simY(4,:) - Data(1,:);
                FitError = Weight.*FitError;
        end
        
        function update_listbox(app, handles)
            % hObject    handle to update (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'Value') returns listbox1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox1
            
            %%% Updates the listbox to match the current workspace
            vars = evalin('base','who');
            set(app.ListBox,'Items',vars)
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.RASGUI, 'off')
            rotate3d(app.RASGUI, 'off');
            pan(app.RASGUI, 'off');
            zoom(app.RASGUI,'off');
        end
        
        function AgingTest(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Holds, Time_ZeroRef, Vars_i, opt)
            %%% Aging law test.
            StateLawFlag = true;
            if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                    Time_ZeroRef,Vars_i,opt);
            %%% Compute the load point slip.
                Slip_f = nan(numel(Time_ZeroRef), 1);
                [~, kk1] = min(abs(Time_ZeroRef - TimeOfStep));
                Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Time_ZeroRef(kk1:end)));
            else
                HoldTime = Holds{1};
                TimeOfHold = Holds{2};
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfHold,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,...
                    HoldTime), Time_ZeroRef,Vars_i,opt);
            %%% Compute the load point slip.
                Slip_f = nan(numel(Time_ZeroRef), 1);
                [~, kk1] = min(abs(Time_ZeroRef - TimeOfHold));
                [~, kk2] = min(abs(Time_ZeroRef - (TimeOfHold + HoldTime)));
                Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                Slip_f(kk1+1:kk2) = Slip_f(kk1);
                Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Time_ZeroRef(kk2:end)));
            end    
            bestY = deval(ODE_Sol, Time_ZeroRef);
            Mu_f = bestY(4,:)';
            
            %%% Plot the test over the detrended data in the fitting axes. 
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    if StateVarFlag
                        PAgingTest = plot(app.FittingAxes, Slip_f, Mu_f, 'Color', [0.6350 0.0780 0.1840], 'LineWidth',...
                            2, 'LineStyle', '--', 'DisplayName','Aging Test 2 State');
                            setappdata(app.TestButton, 'Plot_ATest_2', PAgingTest);
                    else
                        PAgingTest = plot(app.FittingAxes,Slip_f, Mu_f, 'r', 'LineWidth',...
                        2, 'LineStyle', '--', 'DisplayName','Aging Test');
                        setappdata(app.TestButton, 'Plot_ATest', PAgingTest);
                    end
                else
                    if StateVarFlag
                        PAgingTest = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'Color', [0.6350 0.0780 0.1840],...
                            'LineWidth', 2, 'LineStyle', '--', 'DisplayName','Aging Test 2 State');
                        setappdata(app.TestButton, 'Plot_ATest_2', PAgingTest);
                    else
                        PAgingTest = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'r',...
                            'LineWidth', 2, 'LineStyle', '--', 'DisplayName','Aging Test');
                        setappdata(app.TestButton, 'Plot_ATest', PAgingTest);
                    end
                end
                
        end
        
        
        function SlipTest(app, x_0,x_e,v_i,v_f,TimeOfStep,...
                    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Holds, Time_ZeroRef, Vars_i, opt)
            StateLawFlag = false;
            if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfStep,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag),...
                    Time_ZeroRef,Vars_i,opt);
            %%% Compute the load point slip.
                Slip_f = nan(numel(Time_ZeroRef), 1);
                [~, kk1] = min(abs(Time_ZeroRef - TimeOfStep));
                Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                Slip_f(kk1+1:end) = Slip_f(kk1) + v_f*cumsum(diff(Time_ZeroRef(kk1:end)));    
            else
                HoldTime = Holds{1};
                TimeOfHold = Holds{2};
                ODE_Sol = ode45(@(time,Vars)SpringBlock(app, time,Vars,x_0,x_e,v_i,v_f,TimeOfHold,...
                    StateLawFlag,StateVarFlag,EventFlag,StiffnessFlag,MuFlag,...
                    HoldTime), Time_ZeroRef,Vars_i,opt);
            %%% Compute the load point slip
                Slip_f = nan(numel(Time_ZeroRef), 1);
                [~, kk1] = min(abs(Time_ZeroRef - TimeOfHold));
                [~, kk2] = min(abs(Time_ZeroRef - (TimeOfHold + HoldTime)));
                Slip_f(1:kk1) = v_i*Time_ZeroRef(1:kk1);
                Slip_f(kk1+1:kk2) = Slip_f(kk1);
                Slip_f(kk2+1:end) = Slip_f(kk2) + v_f*cumsum(diff(Time_ZeroRef(kk2:end)));
            end    
            bestY = deval(ODE_Sol, Time_ZeroRef);
            Mu_f = bestY(4,:)';
            
            %%% Plot the test over the detrended data in the fitting axes. 
                hold(app.FittingAxes, 'on');
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    if StateVarFlag
                        PSlipTest = plot(app.FittingAxes,Slip_f, Mu_f, 'Color', [0.3010 0.7450 0.9330],...
                            'LineWidth', 2, 'LineStyle', '--', 'DisplayName', 'Slip Test 2 State');
                    else
                        PSlipTest = plot(app.FittingAxes,Slip_f, Mu_f, 'c',...
                            'LineWidth', 2, 'LineStyle', '--', 'DisplayName', 'Slip Test');
                    end
                else
                    if StateVarFlag
                    PSlipTest = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'Color', [0.3010 0.7450 0.9330],...
                        'LineWidth', 2, 'LineStyle', '--','DisplayName','Slip Test 2 State');
                    else
                        PSlipTest = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'c',...
                        'LineWidth', 2, 'LineStyle', '--','DisplayName','Slip Test');
                    end
                end
                %%% hold(app.FittingAxes, 'off');
                if StateVarFlag
                    setappdata(app.TestButton, 'Plot_STest_2', PSlipTest);
                else
                    setappdata(app.TestButton, 'Plot_STest', PSlipTest);
                end
        end
        
        function [exitflagA, residualA] = AgingFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
                    varargout)
                SampleSlipFlag = getappdata(app.FitButton, 'SampleSlipFlag');
                Friction_Detrend = getappdata(app.DetrendButton,'FrictionDetrend_Data');
                FrictionVariability = sum((Friction_Detrend - mean(Friction_Detrend)).^2);
                StateLawFlag = true;
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    try
                    setappdata(app.FitButton,'StepTime',TimeOfStep);
                    [~,x_f,~,~,~,Mu_f,Slip_f,SampleSlip_f,exitflagA,~,residualA,jacobian,resnorm]...
                        = RASFittingLD1(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                        Slip_ZeroRef,Time_ZeroRef,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,Weight,SampleSlipFlag,app);
                    catch ME
                        disp(ME.message)
                        msg = 'ODE solver failure';
                        error(msg);
                    end
                else
                    try
                    HoldTime = varargout{1};
                    TimeOfHold = varargout{2};
                    setappdata(app.FitButton,'Hold_Time',TimeOfHold);
                    [~,x_f,~,~,~,Mu_f,Slip_f,SampleSlip_f,exitflagA,~,residualA,jacobian,resnorm]...
                        = RASFittingLD1(app, x_0,x_e,v_i,v_f,NormStress,TimeOfHold,Friction_Detrend,...
                        Slip_ZeroRef,Time_ZeroRef,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,Weight,SampleSlipFlag,app,HoldTime);
                    catch ME
                        disp(ME.message)
                        msg = 'ODE solver failure';
                        error(msg);
                    end
                end
            
            %%% Compute the R-squared value.
                r4AIC = sum((Mu_f - Friction_Detrend).^8);
                FrictionResidualA = sum((Mu_f - Friction_Detrend).^2);
                FrictionCOD_A = 1 - FrictionResidualA/FrictionVariability;
                if StateVarFlag
                    set(app.AgingRes_2, 'Value', num2str(FrictionCOD_A));
                else
                    set(app.AgingRes, 'Value', num2str(FrictionCOD_A));
                end
            
            %%% Compute standard deviation. Two-sigma error.
                if StateVarFlag
                    x_fA = x_f;
                    if StiffnessFlag == true & MuFlag == true
                        N = numel(Time_ZeroRef) - 7;
                        jacA = jacobian;
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        mu_0Error = ErrorsA(1,1);
                        aError = ErrorsA(2,1);
                        b1Error = ErrorsA(3,1);
                        d_c1Error = ErrorsA(4,1);
                        kError = ErrorsA(5,1);
                        b2Error = ErrorsA(6,1);
                        d_c2Error = ErrorsA(7,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarA(3,6) - 2*CovarA(2,3) - 2*CovarA(2,6));
                    elseif StiffnessFlag == false & MuFlag == true
                        N = numel(Time_ZeroRef) - 6;
                        jacA = [jacobian(:,1:4), jacobian(:,6:7)];
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        mu_0Error = ErrorsA(1,1);
                        aError = ErrorsA(2,1);
                        b1Error = ErrorsA(3,1);
                        d_c1Error = ErrorsA(4,1);
                        b2Error = ErrorsA(5,1);
                        d_c2Error = ErrorsA(6,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarA(3,5) - 2*CovarA(2,3) - 2*CovarA(2,5));
                    elseif StiffnessFlag == true & MuFlag == false
                        N = numel(Time_ZeroRef) - 6;
                        jacA = jacobian(:,2:7);
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        aError = ErrorsA(1,1);
                        b1Error = ErrorsA(2,1);
                        d_c1Error = ErrorsA(3,1);
                        kError = ErrorsA(4,1);
                        b2Error = ErrorsA(5,1);
                        d_c2Error = ErrorsA(6,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarA(2,5) - 2*CovarA(1,2) - 2*CovarA(1,5));
                    else
                        N = numel(Time_ZeroRef) - 5;
                        jacA = [jacobian(:,2:4), jacobian(:,6:7)];
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        aError = ErrorsA(1,1);
                        b1Error = ErrorsA(2,1);
                        d_c1Error = ErrorsA(3,1);
                        b2Error = ErrorsA(4,1);
                        d_c2Error = ErrorsA(5,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarA(2,5) - 2*CovarA(1,2) - 2*CovarA(1,4));
                    end
                    b2String = [strrep(sprintf('%0.2e ', x_fA(6)), 'e-0', 'e-'), char(177),...
                        strrep(sprintf(' %0.2e', b2Error), 'e-0', 'e-')];
                    d_c2String = [sprintf('%0.3f ', x_fA(7)), char(177),...
                        sprintf(' %0.3f', d_c2Error)];
                    set(app.AgingFit_b2Value_2, 'Value', b2String);
                    set(app.AgingFit_dc2Value_2, 'Value', d_c2String);
                else
                    x_fA = x_f(1:5);
                    if StiffnessFlag == true & MuFlag == true
                        N = numel(Time_ZeroRef) - 5;
                        jacA = jacobian(:,1:5);
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        mu_0Error = ErrorsA(1,1);
                        aError = ErrorsA(2,1);
                        b1Error = ErrorsA(3,1);
                        d_c1Error = ErrorsA(4,1);
                        kError = ErrorsA(5,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(2,3));
                    elseif StiffnessFlag == false & MuFlag == true
                        N = numel(Time_ZeroRef) - 4;
                        jacA = jacobian(:,1:4);
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        mu_0Error = ErrorsA(1,1);
                        aError = ErrorsA(2,1);
                        b1Error = ErrorsA(3,1);
                        d_c1Error = ErrorsA(4,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(2,3));
                    elseif StiffnessFlag == true & MuFlag == false
                        N = numel(Time_ZeroRef) - 4;
                        jacA = jacobian(:,2:5);
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        aError = ErrorsA(1,1);
                        b1Error = ErrorsA(2,1);
                        d_c1Error = ErrorsA(3,1);
                        kError = ErrorsA(4,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(1,2));
                    else
                        N = numel(Time_ZeroRef) - 3;
                        jacA = jacobian(:,2:4);
                        CovarA = resnorm*(inv(jacA'*jacA))/N;
                        ErrorsA = 2*sqrt(resnorm*diag(inv(jacA'*jacA))/N);
                        aError = ErrorsA(1,1);
                        b1Error = ErrorsA(2,1);
                        d_c1Error = ErrorsA(3,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarA(1,2));
                    end
                end
            
            %%% Compute AIC
                if StateVarFlag 
                    n = length(x_0(1)); % Number of data points 
                    k = 6; % Number of parameters  (1 for one state and 2 for two state
                    AIC = n * log(r4AIC / n) + 2*k; % Calculate AIC 
                    set(app.AgingAIC_2, 'Value', num2str(AIC));
                else
                    n = length(x_0(1)); % Number of data points 
                    k = 4; % Number of parameters  (1 for one state and 2 for two state)
                    AIC = n * log(r4AIC / n) + 2*k; % Calculate AIC 
                    set(app.AgingAIC, 'Value', num2str(AIC));
                end


            %%% Format the numbers for main parameters to print in the gui.
                if StiffnessFlag == true
                    kString = [strrep(sprintf('%0.2e ', x_fA(5)), 'e-0', 'e-'), char(177),...
                        strrep(sprintf(' %0.2e', kError), 'e-0', 'e-')];
                    if StateVarFlag
                        set(app.AgingFit_kValue_2, 'Value', kString);
                    else
                        set(app.AgingFit_kValue, 'Value', kString);
                    end
                else
                    x_f(5,1) = x_e(2,1);
                    kError = 0;
                end
                if MuFlag == true
                    mu_0String = [strrep(sprintf('%0.2e ', x_fA(1)), 'e-0', 'e-'), char(177),...
                        strrep(sprintf(' %0.2e', mu_0Error), 'e-0', 'e-')];
                    if StateVarFlag
                    set(app.AgingFit_muValue_2, 'Value', mu_0String);
                    else
                    set(app.AgingFit_muValue, 'Value', mu_0String);
                    end
                else
                    x_f(1,1) = x_e(1,1);
                    mu_0Error = 0;
                end
                aString = [strrep(sprintf('%0.2e ', x_fA(2)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', aError), 'e-0', 'e-')];
                b1String = [strrep(sprintf('%0.2e ', x_fA(3)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', b1Error), 'e-0', 'e-')];
                d_c1String = [sprintf('%0.3f ', x_fA(4)), char(177),...
                    sprintf(' %0.3f', d_c1Error)];
            %%% Display the fitted parameters in aging law fields.
                if StateVarFlag
                    set(app.AgingFit_aValue_2, 'Value', aString);
                    set(app.AgingFit_bValue_2, 'Value', b1String);
                    set(app.AgingFit_dcValue_2, 'Value', d_c1String);
                else
                    set(app.AgingFit_aValue, 'Value', aString);
                    set(app.AgingFit_bValue, 'Value', b1String);
                    set(app.AgingFit_dcValue, 'Value', d_c1String);
                end
            %%% Plot the fit over the detrended data in the fitting axes.
                hold(app.FittingAxes, 'on');
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    if StateVarFlag
                        PAgingFit = plot(app.FittingAxes, Slip_f, Mu_f, 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 2, 'DisplayName', 'Aging Fit 2 State');
                    else
                        PAgingFit = plot(app.FittingAxes,Slip_f, Mu_f, 'r', 'LineWidth', 2, 'DisplayName', 'Aging Fit');
                    end
                else
                    if StateVarFlag
                        PAgingFit = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 2, 'DisplayName', 'Aging Fit 2 State');
                    else
                        PAgingFit = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'r', 'LineWidth', 2, 'DisplayName', 'Aging Fit');
                    end
                end
                if StateVarFlag
                    setappdata(app.TestButton, 'Plot_AFit_2', PAgingFit);
                else
                    setappdata(app.TestButton, 'Plot_AFit', PAgingFit);
                end
                hold(app.FittingAxes, 'off');
            %%% Store the fitted parameters and data.
                if StateVarFlag
                    setappdata(app.FitButton,'X0_2',x_0);
                    setappdata(app.FitButton,'XF_Aging_2',x_f);
                    setappdata(app.FitButton,'SlipFit_Aging_2',Slip_f);
                    setappdata(app.FitButton,'FrictionFit_Aging_2',Mu_f);
                    setappdata(app.FitButton,'CovarianceA_2',CovarA);
                else
                    setappdata(app.FitButton,'X0',x_0);
                    setappdata(app.FitButton,'XF_Aging',x_f);
                    setappdata(app.FitButton,'SlipFit_Aging',Slip_f);
                    setappdata(app.FitButton,'FrictionFit_Aging',Mu_f);
                    setappdata(app.FitButton,'CovarianceA',CovarA);
                end
            
            %%% Store the error intervals.
                if StateVarFlag == true
                    AgingError = [mu_0Error; aError; b1Error; b2Error; d_c1Error;...
                        d_c2Error; kError; aminusbError];
                else
                    AgingError = [mu_0Error; aError; b1Error; 0; d_c1Error;...
                        0; kError; aminusbError];
                end
                if StateVarFlag
                    setappdata(app.FitButton,'AgingErrors_2',AgingError);
                else
                    setappdata(app.FitButton,'AgingErrors',AgingError);
                end
        end
        
        function [exitflagS, residualS] = SlipFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
                    varargout)
                SampleSlipFlag = getappdata(app.FitButton, 'SampleSlipFlag');
                Friction_Detrend = getappdata(app.DetrendButton,'FrictionDetrend_Data');
                FrictionVariability = sum((Friction_Detrend - mean(Friction_Detrend)).^2);
                StateLawFlag = false;
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    try
                    setappdata(app.FitButton,'StepTime',TimeOfStep);
                    [~,x_f,~,~,~,Mu_f,Slip_f,SampleSlip_f,exitflagS,~,residualS,jacobian,resnorm]...
                        = RASFittingLD1(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Friction_Detrend,...
                        Slip_ZeroRef,Time_ZeroRef,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,Weight,SampleSlipFlag,app);
                    catch ME
                        disp(ME.message)
                        msg = 'ODE solver failure';
                        error(msg);
                    end
                else
                    try
                    HoldTime = varargout{1};
                    TimeOfHold = varargout{2};
                    setappdata(app.FitButton,'Hold_Time',TimeOfHold);
                    [~,x_f,~,~,~,Mu_f,Slip_f,SampleSlip_f,exitflagS,~,residualS,jacobian,resnorm]...
                        = RASFittingLD1(app, x_0,x_e,v_i,v_f,NormStress,TimeOfHold,Friction_Detrend,...
                        Slip_ZeroRef,Time_ZeroRef,StateLawFlag,StateVarFlag,EventFlag,...
                        StiffnessFlag,MuFlag,Weight,SampleSlipFlag,app,HoldTime);
                    catch ME
                        disp(ME.message)
                        msg = 'ODE solver failure';
                        error(msg);
                    end
                end
            
            %%% Compute, display, and store R-squared values.
                r4AIC = sum((Mu_f - Friction_Detrend).^8);
                FrictionResidualS = sum((Mu_f - Friction_Detrend).^2);
                FrictionCOD_S = 1 - FrictionResidualS/FrictionVariability;
                if StateVarFlag
                    set(app.SlipRes_2, 'Value', num2str(FrictionCOD_S));
                else
                    set(app.SlipRes, 'Value', num2str(FrictionCOD_S));
                end
            %%%---------------------------------------------------------------------%%%
            
            %%% Compute standard deviation. Two-sigma error.
                if StateVarFlag
                    x_fS = x_f;
                    if StiffnessFlag == true & MuFlag == true
                        N = numel(Time_ZeroRef) - 7;
                        jacS = jacobian;
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        mu_0Error = ErrorsS(1,1);
                        aError = ErrorsS(2,1);
                        b1Error = ErrorsS(3,1);
                        d_c1Error = ErrorsS(4,1);
                        kError = ErrorsS(5,1);
                        b2Error = ErrorsS(6,1);
                        d_c2Error = ErrorsS(7,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarS(3,6) - 2*CovarS(2,3) - 2*CovarS(2,6));
                    elseif StiffnessFlag == false & MuFlag == true
                        N = numel(Time_ZeroRef) - 6;
                        jacS = [jacobian(:,1:4), jacobian(:,6:7)];
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        mu_0Error = ErrorsS(1,1);
                        aError = ErrorsS(2,1);
                        b1Error = ErrorsS(3,1);
                        d_c1Error = ErrorsS(4,1);
                        b2Error = ErrorsS(5,1);
                        d_c2Error = ErrorsS(6,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarS(3,5) - 2*CovarS(2,3) - 2*CovarS(2,5));
                    elseif StiffnessFlag == true & MuFlag == false
                        N = numel(Time_ZeroRef) - 6;
                        jacS = jacobian(:,2:7);
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        aError = ErrorsS(1,1);
                        b1Error = ErrorsS(2,1);
                        d_c1Error = ErrorsS(3,1);
                        kError = ErrorsS(4,1);
                        b2Error = ErrorsS(5,1);
                        d_c2Error = ErrorsS(6,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarS(2,5) - 2*CovarS(1,2) - 2*CovarS(1,5));
                    else
                        N = numel(Time_ZeroRef) - 5;
                        jacS = [jacobian(:,2:4), jacobian(:,6:7)];
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        aError = ErrorsS(1,1);
                        b1Error = ErrorsS(2,1);
                        d_c1Error = ErrorsS(3,1);
                        b2Error = ErrorsS(4,1);
                        d_c2Error = ErrorsS(5,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 + (b2Error/2)^2 ...
                            + 2*CovarS(2,4) - 2*CovarS(1,2) - 2*CovarS(1,4));
                    end
                    b2String = [strrep(sprintf('%0.2e ', x_fS(6)), 'e-0', 'e-'), char(177),...
                        strrep(sprintf(' %0.2e', b2Error), 'e-0', 'e-')];
                    d_c2String = [sprintf('%0.3f ', x_fS(7)), char(177),...
                        sprintf(' %0.3f', d_c2Error)];
                    set(app.SlipFit_b2Value_2, 'Value', b2String);
                    set(app.SlipFit_dc2Value_2, 'Value', d_c2String);
                else
                    x_fS = x_f(1:5);
                    if StiffnessFlag == true & MuFlag == true
                        N = numel(Time_ZeroRef) - 5;
                        jacS = jacobian(:,1:5);
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        mu_0Error = ErrorsS(1,1);
                        aError = ErrorsS(2,1);
                        b1Error = ErrorsS(3,1);
                        d_c1Error = ErrorsS(4,1);
                        kError = ErrorsS(5,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarS(2,3));
                    elseif StiffnessFlag == false & MuFlag == true
                        N = numel(Time_ZeroRef) - 4;
                        jacS = jacobian(:,1:4);
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        mu_0Error = ErrorsS(1,1);
                        aError = ErrorsS(2,1);
                        b1Error = ErrorsS(3,1);
                        d_c1Error = ErrorsS(4,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarS(2,3));
                    elseif StiffnessFlag == true & MuFlag == false
                        N = numel(Time_ZeroRef) - 4;
                        jacS = jacobian(:,2:5);
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        aError = ErrorsS(1,1);
                        b1Error = ErrorsS(2,1);
                        d_c1Error = ErrorsS(3,1);
                        kError = ErrorsS(4,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarS(1,2));
                    else
                        N = numel(Time_ZeroRef) - 3;
                        jacS = jacobian(:,2:4);
                        CovarS = resnorm*(inv(jacS'*jacS))/N;
                        ErrorsS = 2*sqrt(resnorm*diag(inv(jacS'*jacS))/N);
                        aError = ErrorsS(1,1);
                        b1Error = ErrorsS(2,1);
                        d_c1Error = ErrorsS(3,1);
                        aminusbError = 2*sqrt((aError/2)^2 + (b1Error/2)^2 - 2*CovarS(1,2));
                    end
                end
            
            %%% Compute AIC
                if StateVarFlag 
                    n = length(x_0(1));; % Number of data points 
                    k = 6; % Number of parameters  (4 for one state and 6 for two state)
                    AIC = n * log(r4AIC / n) + 2*k; % Calculate AIC 
                    set(app.SlipAIC_2, 'Value', num2str(AIC));
                else
                    n = length(x_0(1));; % Number of data points 
                    k = 4; % Number of parameters  (4 for one state and 6 for two state)
                    AIC = n * log(r4AIC / n) + 2*k; % Calculate AIC 
                    set(app.SlipAIC, 'Value', num2str(AIC));
                end
                
            %%% Format the numbers for main parameters to print in the gui.
                if StiffnessFlag == true
                    kString = [strrep(sprintf('%0.2e ', x_fS(5)), 'e-0', 'e-'), char(177),...
                        strrep(sprintf(' %0.2e', kError), 'e-0', 'e-')];
                    if StateVarFlag
                        set(app.SlipFit_kValue_2, 'Value', kString);
                    else
                        set(app.SlipFit_kValue, 'Value', kString);
                    end
                else
                    x_f(5,1) = x_e(2,1);
                    kError = 0;
                end
                if MuFlag == true
                    mu_0String = [strrep(sprintf('%0.2e ', x_fS(1)), 'e-0', 'e-'), char(177),...
                        strrep(sprintf(' %0.2e', mu_0Error), 'e-0', 'e-')];
                    if StateVarFlag
                        set(app.SlipFit_muValue_2, 'Value', mu_0String);
                    else
                        set(app.SlipFit_muValue, 'Value', mu_0String);
                    end
                else
                    x_f(1,1) = x_e(1,1);
                    mu_0Error = 0;
                end
                aString = [strrep(sprintf('%0.2e ', x_fS(2)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', aError), 'e-0', 'e-')];
                b1String = [strrep(sprintf('%0.2e ', x_fS(3)), 'e-0', 'e-'), char(177),...
                    strrep(sprintf(' %0.2e', b1Error), 'e-0', 'e-')];
                d_c1String = [sprintf('%0.3f ', x_fS(4)), char(177),...
                    sprintf(' %0.3f', d_c1Error)];
            %%% Display the fitted parameters in Slip law fields.
                if StateVarFlag
                    set(app.SlipFit_aValue_2, 'Value', aString);
                    set(app.SlipFit_bValue_2, 'Value', b1String);
                    set(app.SlipFit_dcValue_2, 'Value', d_c1String);
                else
                    set(app.SlipFit_aValue, 'Value', aString);
                    set(app.SlipFit_bValue, 'Value', b1String);
                    set(app.SlipFit_dcValue, 'Value', d_c1String);
                end
            %%% Plot the fit over the detrended data in the fitting axes.
                hold(app.FittingAxes, 'on');
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    if StateVarFlag
                        PSlipFit = plot(app.FittingAxes,Slip_f, Mu_f, 'Color', [0.3010 0.7450 0.9330], 'LineWidth', 2, 'DisplayName', 'Slip Fit 2 State');
                    else
                        PSlipFit = plot(app.FittingAxes,Slip_f, Mu_f, 'c', 'LineWidth', 2, 'DisplayName', 'Slip Fit');
                    end
                else
                    if StateVarFlag
                        PSlipFit = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'Color', [0.3010 0.7450 0.9330], 'LineWidth', 2, 'DisplayName', 'Slip Fit 2 State');
                    else
                        PSlipFit = plot(app.FittingAxes,Time_ZeroRef, Mu_f, 'c', 'LineWidth', 2, 'DisplayName', 'Slip Fit');
                    end
                end
                if StateVarFlag
                    setappdata(app.TestButton, 'Plot_SFit_2', PSlipFit);
                else
                    setappdata(app.TestButton, 'Plot_SFit', PSlipFit);
                end
                hold(app.FittingAxes, 'off');
            %%% Store the fitted parameters and data.
                if StateVarFlag
                    setappdata(app.FitButton,'X0_2',x_0);
                    setappdata(app.FitButton,'XF_Slip_2',x_f);
                    setappdata(app.FitButton,'SlipFit_Slip_2',Slip_f);
                    setappdata(app.FitButton,'FrictionFit_Slip_2',Mu_f);
                    setappdata(app.FitButton,'CovarianceS_2',CovarS);
                else
                    setappdata(app.FitButton,'X0',x_0);
                    setappdata(app.FitButton,'XF_Slip',x_f);
                    setappdata(app.FitButton,'SlipFit_Slip',Slip_f);
                    setappdata(app.FitButton,'FrictionFit_Slip',Mu_f);
                    setappdata(app.FitButton,'CovarianceS',CovarS);
                end
            
            %%% Store the error intervals.
                if StateVarFlag == true
                    SlipError = [mu_0Error; aError; b1Error; b2Error; d_c1Error;...
                        d_c2Error; kError; aminusbError];
                    setappdata(app.FitButton,'SlipErrors_2',SlipError);
                else
                    SlipError = [mu_0Error; aError; b1Error; 0; d_c1Error;...
                        0; kError; aminusbError];
                    setappdata(app.FitButton,'SlipErrors',SlipError);
                end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function RSFit3000_OpeningFcn(app, varargin)
            % --- Executes just before RSFit3000 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.RASGUI, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            % varargin   command line arguments to RSFit3000 (see VARARGIN)
            
            %%% Choose default command line output for RSFit3000
            % app.output = hObject;
            
            %%% Update app structure
            guidata(hObject, handles);
            
            %%% Populate the listbox
            update_listbox(app, handles)
        
            %%% Set the sample slip flag to "false", and disable the sample slip field.
            setappdata(app.FitButton, 'SampleSlipFlag', false);
            
            %%% Set the velocity step button to "on", disable the SHS panel, and set the
            %%% event flag.
            set(app.EventButtonGroup,'selectedobject',app.VelocityStepButton);
            setappdata(app.FitButton, 'EventFlag', true);
            set(findall(app.SHSPanel, '-property', 'Enable'), 'Enable', 'on');
            app.SHSPanel.Enable = 'off';
            
            %%% Set the two state variable button to "off" and set the state variable
            %%% flag.
            setappdata(app.FitButton, 'StateVarFlag', true);
            %%% Disable the additional state variable fields.
            set(app.Guess_b2Value, 'Enable', 'on')
            set(app.AgingFit_b2Value_2, 'Enable', 'on')
            set(app.SlipFit_b2Value_2, 'Enable', 'on')
            set(app.AgingFit_muValue_2, 'Enable', 'on');
            set(app.AgingFit_aValue_2, 'Enable', 'on');
            set(app.AgingFit_bValue_2, 'Enable', 'on');
            set(app.AgingFit_dcValue_2, 'Enable', 'on');
            set(app.AgingFit_kValue_2, 'Enable', 'on');
            set(app.Guess_dc2Value, 'Enable', 'on')
            set(app.AgingFit_dc2Value_2, 'Enable', 'on')
            set(app.SlipFit_dc2Value_2, 'Enable', 'on')
            set(app.SlipFit_muValue_2, 'Enable', 'on');
            set(app.SlipFit_aValue_2, 'Enable', 'on');
            set(app.SlipFit_bValue_2, 'Enable', 'on');
            set(app.SlipFit_dcValue_2, 'Enable', 'on');
            set(app.SlipFit_kValue_2, 'Enable', 'on');
            
            % %%% Set the StiffnessFlag and MuFlag to "true", WeightFlag to "off", and
            % %%% the respective buttons to "off"
            set(app.WeightButton, 'value', 0);
            setappdata(app.FitButton, 'WeightFlag', false);
            set(app.MuButton, 'value', 0);
            setappdata(app.FitButton, 'MuFlag', true);
            set(app.StiffnessButton, 'value', 0);
            setappdata(app.FitButton, 'StiffnessFlag', true);
            
            %%% Disable the weighting panel
            set(findall(app.WeightPanel, '-property', 'Enable'), 'Enable', 'off');
            
            %%% Set both Aging and Slip Law fit buttons to on, and enable their fields.
            set(app.AgingButton, 'value', 1);
            setappdata(app.FitButton, 'AgingLawFlag', true);
            set(app.AgingFit_muValue, 'Enable', 'on');
            set(app.AgingFit_aValue, 'Enable', 'on');
            set(app.AgingFit_bValue, 'Enable', 'on');
            set(app.AgingFit_dcValue, 'Enable', 'on');
            set(app.AgingFit_kValue, 'Enable', 'on');
            set(app.SlipButton, 'value', 1);
            setappdata(app.FitButton, 'SlipLawFlag', true);
            set(app.SlipFit_muValue, 'Enable', 'on');
            set(app.SlipFit_aValue, 'Enable', 'on');
            set(app.SlipFit_bValue, 'Enable', 'on');
            set(app.SlipFit_dcValue, 'Enable', 'on');
            set(app.SlipFit_kValue, 'Enable', 'on');
            
            %%% Display the logo.
            try
                Logo = imread('RSFitLogo_small.jpg');
                image(app.LogoAxes, Logo);
                axis(app.LogoAxes, 'off');
                axis image
            catch
                axis(app.LogoAxes, 'off');
                warning('Logo image missing.');
            end
        end

        % Button pushed function: ClearFitLines
        function ClearFitLines_Callback(app, event)
            % --- Executes on button press in ClearFitLines.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ClearFitLines (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% See if there's any fit lines plotted and delete them.
            children = get(app.FittingAxes, 'children');
            EventFlag = getappdata(app.FitButton, 'EventFlag');
            if numel(children) > 1
                for i = 1:numel(children)
            %%% Check if test lines are present.
                    if strcmp('Aging Law Test', get(children(i), 'DisplayName'))
                        PAgingTest = getappdata(app.TestButton, 'Plot_ATest');
                    elseif strcmp('Slip Law Test', get(children(i), 'DisplayName'))
                        PSlipTest = getappdata(app.TestButton, 'Plot_STest');
                    end
                    if strcmp('-', get(children(i), 'LineStyle'))
                        delete(children(i));
                    end
                end
            %%% Reset the legend
                LegLoc = 'best';
                if exist('PAgingTest', 'var') & exist('PSlipTest', 'var')
                    legend(app.FittingAxes, [PAgingTest, PSlipTest], 'Aging Law Test',...
                        'Slip Law Test', 'Location', LegLoc);
                elseif exist('PAgingTest', 'var') & exist('PSlipTest', 'var') == 0
                    legend(app.FittingAxes, PAgingTest, 'Aging Law Test',...
                        'Location', LegLoc);
                elseif exist('PAgingTest', 'var') == 0 & exist('PSlipTest', 'var')
                    legend(app.FittingAxes, PSlipTest, 'Slip Law Test',...
                        'Location', LegLoc);
                else
                    legend('off');
                end
            end
        end

        % Button pushed function: ClearPointsButton
        function ClearPointsButton_Callback(app, event)
            % --- Executes on button press in ClearPointsButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ClearPointsButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% See if there is a previous detrend line plotted and delete it.
                children = get(app.MainAxes, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Clear all the displayed and stored values from any previous detrend.
                set(app.Point1SlipValue, 'Value', [ ]);
                set(app.Point1MuValue, 'Value', [ ]);
                set(app.Point2SlipValue, 'Value', [ ]);
                set(app.Point2MuValue, 'Value',[ ]);
                set(app.ReferencePointSlipValue, 'Value', [ ]);
                set(app.ReferencePointMuValue, 'Value',[ ]);
                setappdata(app.ReferencePointSlipValue,'ReferencePoint_SlipValue', [ ]);
                set(app.SlopeValue, 'Value', [ ]);
                setappdata(app.SlopeValue,'Slope_Value', [ ]);
        end

        % Button pushed function: ClearTestLines
        function ClearTestLines_Callback(app, event)
            % --- Executes on button press in ClearTestLines.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to ClearTestLines (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% See if there's any test lines plotted and delete them.
            children = get(app.FittingAxes, 'children');
            EventFlag = getappdata(app.FitButton, 'EventFlag');
            if numel(children) > 1
                for i = 1:numel(children)
            %%% Check if fit lines are present.
                    if strcmp('Aging Law Fit', get(children(i), 'DisplayName'))
                        PAgingFit = getappdata(app.TestButton, 'Plot_AFit');
                    elseif strcmp('Slip Law Fit', get(children(i), 'DisplayName'))
                        PSlipFit = getappdata(app.TestButton, 'Plot_SFit');
                    end
                    if strcmp('--', get(children(i), 'LineStyle'))
                        delete(children(i));
                    end
                end
            %%% Reset the legend
                LegLoc = 'best';
                if exist('PAgingFit', 'var') & exist('PSlipFit', 'var')
                    legend(app.FittingAxes, [PAgingFit, PSlipFit], 'Aging Law Fit',...
                        'Slip Law Fit', 'Location', LegLoc);
                elseif exist('PAgingFit', 'var') & exist('PSlipFit', 'var') == 0
                    legend(app.FittingAxes, PAgingFit, 'Aging Law Fit',...
                         'Location', LegLoc);
                elseif exist('PAgingFit', 'var') == 0 & exist('PSlipFit', 'var')
                    legend(app.FittingAxes, PSlipFit, 'Slip Law Fit',...
                         'Location', LegLoc);
                else
                    legend('off');
                end
            end
        end

        % Button pushed function: CreateStructureButton
        function CreateStructureButton_Callback(app, event)
            % --- Executes on button press in CreateStructureButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to CreateStructureButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Check Flags.
                EventFlag = getappdata(app.FitButton, 'EventFlag');
                StiffnessFlag = getappdata(app.FitButton, 'StiffnessFlag');
                MuFlag = getappdata(app.FitButton, 'MuFlag');
                StateVarFlag = getappdata(app.FitButton, 'StateVarFlag');
                WeightFlag = getappdata(app.FitButton, 'WeightFlag');
            %%% Check what fits were done.
                AgingLawFlag = getappdata(app.FitButton, 'AgingLawFlag');
                SlipLawFlag = getappdata(app.FitButton, 'SlipLawFlag');
            %%% Get all of the data and parameters and save them in a structure named
            %%% after the entry in the "Fit Tag" field.
            %%% Read the detrending point locations from their text boxes.
                Point1_SlipString = get(app.Point1SlipValue,'Value');
                Point1_FrictionString = get(app.Point1MuValue,'Value');
                Point2_SlipString = get(app.Point2SlipValue,'Value');
                Point2_FrictionString = get(app.Point2MuValue,'Value');
                Point1_Slip = str2double(Point1_SlipString);
                Point1_Friction = str2double(Point1_FrictionString);
                Point2_Slip = str2double(Point2_SlipString);
                Point2_Friction = str2double(Point2_FrictionString);
            %%% Read the detrending reference point.
                ReferencePoint_SlipString = get(app.ReferencePointSlipValue,'Value');
                ReferencePoint_FrictionString = get(app.ReferencePointMuValue,'Value');
                ReferencePoint_Slip = str2double(ReferencePoint_SlipString);
                ReferencePoint_Friction = str2double(ReferencePoint_FrictionString);
            %%% Read linear fit slope value from text box.
                m_String = get(app.SlopeValue,'Value');
                m = str2double(m_String);
            %%% Get the stored zoomed slip and friction data and reference slip.
                SlipZoom = getappdata(app.SlopeValue,'SlipZoom_Data');
                FrictionZoom = getappdata(app.SlopeValue,'FrictionZoom_Data');
                Friction_Detrend = getappdata(app.DetrendButton,'FrictionDetrend_Data');
                TimeZoom = getappdata(app.SlopeValue,'TimeZoom_Data');
                NormalStressZoom = getappdata(app.SlopeValue, 'NormStressZoomData');
            %%% Get parameter guesses, fitted parameters, fitted data, time of velocity
            %%% step.
                x_0 = getappdata(app.FitButton,'X0');
                x_fAging = getappdata(app.FitButton,'XF_Aging');
                AgingError = getappdata(app.FitButton, 'AgingErrors');
                x_fSlip = getappdata(app.FitButton,'XF_Slip');
                SlipError = getappdata(app.FitButton, 'SlipErrors');
                Slip_fAging = getappdata(app.FitButton,'SlipFit_Aging');
                Mu_fAging = getappdata(app.FitButton,'FrictionFit_Aging');
                Slip_fSlip = getappdata(app.FitButton,'SlipFit_Slip');
                Mu_fSlip = getappdata(app.FitButton,'FrictionFit_Slip');

                x_0_2 = getappdata(app.FitButton,'X0_2');
                x_fAging_2 = getappdata(app.FitButton,'XF_Aging_2');
                AgingError_2 = getappdata(app.FitButton, 'AgingErrors_2');
                x_fSlip_2 = getappdata(app.FitButton,'XF_Slip_2');
                SlipError_2 = getappdata(app.FitButton, 'SlipErrors_2');
                Slip_fAging_2 = getappdata(app.FitButton,'SlipFit_Aging_2');
                Mu_fAging_2 = getappdata(app.FitButton,'FrictionFit_Aging_2');
                Slip_fSlip_2 = getappdata(app.FitButton,'SlipFit_Slip_2');
                Mu_fSlip_2 = getappdata(app.FitButton,'FrictionFit_Slip_2');
            %%% Velocity values.
            if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                InitialVel_String = get(app.InitialVelocityValue,'Value');
                v_i = str2double(InitialVel_String);
                FinalVel_String = get(app.FinalVelocityValue,'Value');
                v_f = str2double(FinalVel_String);
                StepSlip_String = get(app.StepSlipValue, 'Value');
                StepSlip = str2double(StepSlip_String);
                StepFriction_String = get(app.StepFrictionValue, 'Value');
                StepFriction = str2double(StepFriction_String);
            else
                InitialVel_String = get(app.LoadVelocityValue,'Value');
                v_i = str2double(InitialVel_String);
                FinalVel_String = get(app.ReloadVelocityValue,'Value');
                v_f = str2double(FinalVel_String);
                HoldTime_String = get(app.HoldTime,'Value');
                HoldTime = str2double(HoldTime_String);
                slip_hold = get(app.HoldSlipValue,'Value');
                HoldSlip = str2double(slip_hold);
                friction_hold = get(app.HoldFrictionValue, 'Value');
                HoldFriction = str2double(friction_hold);
            end
            %%% Get time of velocity step by finding the location of the step in the
            %%% slip data and pulling out the corresponding time.
                Slip_fAging = Slip_fAging + SlipZoom(1);
                Slip_fSlip = Slip_fSlip + SlipZoom(1);
            %%% Get the normal stress at the time of the step.
                NormStress = getappdata(app.FitButton, 'NormStressStep');
            %%% Get the weight information.
                Weight = getappdata(app.WeightLocButton, 'Weight_Function')';
                WeightParameters = getappdata(app.WeightLocButton, 'Weight_Info');
                if WeightFlag
                    slip_weight = get(app.WeightSlipValue, 'Value');
                    WeightSlip = str2double(slip_weight);
                    friction_weight = get(app.WeightFrictionValue, 'Value');
                    WeightFriction = str2double(friction_weight);
                else
                    WeightSlip = [];
                    WeightFriction = [];
                end
            %%% Get the exit flags and residuals
                ExitFlags = getappdata(app.FitButton, 'ExitFlag');
                FrictionVariability = sum((Friction_Detrend - mean(Friction_Detrend)).^2);
                if AgingLawFlag == 1 & SlipLawFlag == 1
                    ExitAging = ExitFlags(1);
                    ExitSlip = ExitFlags(3);
                    FrictionResidualA = sum((Mu_fAging - Friction_Detrend).^2);
                    FrictionResidualS = sum((Mu_fSlip - Friction_Detrend).^2);
                    FrictionCOD_A = 1 - FrictionResidualA/FrictionVariability;
                    FrictionCOD_S = 1 - FrictionResidualS/FrictionVariability;
                    ExitAging_2 = ExitFlags(2);
                    ExitSlip_2 = ExitFlags(4);
                    FrictionResidualA_2 = sum((Mu_fAging_2 - Friction_Detrend).^2);
                    FrictionResidualS_2 = sum((Mu_fSlip_2 - Friction_Detrend).^2);
                    FrictionCOD_A_2 = 1 - FrictionResidualA_2/FrictionVariability;
                    FrictionCOD_S_2 = 1 - FrictionResidualS_2/FrictionVariability;
                elseif AgingLawFlag == 1
                    ExitAging = ExitFlags(1);
                    ExitAging_2 = ExitFlags(2);
                    FrictionResidualA = sum((Mu_fAging - Friction_Detrend).^2);
                    FrictionResidualA_2 = sum((Mu_fAging_2 - Friction_Detrend).^2);
                    FrictionCOD_A = 1 - FrictionResidualA/FrictionVariability;
                    FrictionCOD_A_2 = 1 - FrictionResidualA_2/FrictionVariability;
                elseif SlipLawFlag == 1
                    ExitSlip = ExitFlags(1);
                    ExitSlip_2 = ExitFlags(2);
                    FrictionResidualS = sum((Mu_fSlip - Friction_Detrend).^2);
                    FrictionCOD_S_2 = 1 - FrictionResidualS_2/FrictionVariability;
                    FrictionCOD_S = 1 - FrictionResidualS/FrictionVariability;
                    FrictionCOD_S_2 = 1 - FrictionResidualS_2/FrictionVariability;
                end
            
            %%% Create the structure and store the data.
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    TimeOfStep = getappdata(app.FitButton,'StepTime');
                    TimeOfStep = TimeOfStep + TimeZoom(1);
                    if AgingLawFlag == 1
                        if m == 0
                            AgingFitData = [Slip_fAging, Mu_fAging];
                            AgingFitData_2 = [Slip_fAging_2, Mu_fAging_2];
                        else
                            Mu_fAging_Retrend = Mu_fAging - m*(ReferencePoint_Slip...
                                - Slip_fAging);
                            Mu_fAging_Retrend_2 = Mu_fAging_2 - m*(ReferencePoint_Slip...
                                - Slip_fAging_2);
                            AgingFitData = [Slip_fAging, Mu_fAging, Mu_fAging_Retrend];
                            AgingFitData_2 = [Slip_fAging_2, Mu_fAging_2, Mu_fAging_Retrend_2];
                        end
                    else
                        AgingFitData = [ ];
                        AgingFitData_2 = [ ];
                    end
                    if SlipLawFlag == 1
                        if m == 0
                            SlipFitData = [Slip_fSlip, Mu_fSlip];
                            SlipFitData_2 = [Slip_fSlip_2, Mu_fSlip_2];
                        else
                            Mu_fSlip_Retrend = Mu_fSlip - m*(ReferencePoint_Slip...
                                - Slip_fSlip);
                            Mu_fSlip_Retrend_2 = Mu_fSlip_2 - m*(ReferencePoint_Slip...
                                - Slip_fSlip_2);
                            SlipFitData_2 = [Slip_fSlip_2, Mu_fSlip_2, Mu_fSlip_Retrend_2];
                        end
                    else
                        SlipFitData = [ ];
                        SlipFitData_2 = [ ];
                    end
            
                    StepData = struct('LoadPointDisplacementData', SlipZoom, 'FrictionData', FrictionZoom,...
                        'FrictionDataDetrended', Friction_Detrend, 'TimeData', TimeZoom,...
                        'NormalStressData', NormalStressZoom, 'DetrendParameters', [],...
                        'FittingOptions', [], 'VelocityStepParameters', [], 'GuessParameters', [],...
                        'AgingLawParameters', [], 'AgingLawParameters2State', [], 'SlipLawParameters', [], 'SlipLawParameters2State', [], 'AgingLawFit',...
                        AgingFitData, 'AgingLawFit2State', AgingFitData_2,'SlipLawFit', SlipFitData, 'SlipLawFit2State', SlipFitData_2, 'WeightInfo', []);
            
                    StepData.VelocityStepParameters = struct('StepLocation',[StepSlip, StepFriction],...
                        'InitialVelocity', v_i, 'FinalVelocity',...
                        v_f, 'TimeOfStep', TimeOfStep, 'NormalStress', NormStress);
            
                else
                    TimeOfHold = getappdata(app.FitButton,'Hold_Time');
                    TimeOfHold = TimeOfHold + TimeZoom(1);
                    if AgingLawFlag == 1
                        if m == 0
                            AgingFitData = [Slip_fAging, Mu_fAging];
                            AgingFitData_2 = [Slip_fAging_2, Mu_fAging_2];
                        else
                            Mu_fAging_Retrend = Mu_fAging - m*(ReferencePoint_Slip...
                                - Slip_fAging);
                            Mu_fAging_Retrend_2 = Mu_fAging_2 - m*(ReferencePoint_Slip...
                                - Slip_fAging_2);
                            AgingFitData = [Slip_fAging, Mu_fAging, Mu_fAging_Retrend];
                            AgingFitData_2 = [Slip_fAging_2, Mu_fAging_2, Mu_fAging_Retrend_2];
                        end
                    else
                        AgingFitData = [ ];
                        AgingFitData_2 = [ ];
                    end
                    if SlipLawFlag == 1
                        if m == 0
                            SlipFitData = [Slip_fSlip, Mu_fSlip];
                            SlipFitData_2 = [Slip_fSlip_2, Mu_fSlip_2];
                        else
                            Mu_fSlip_Retrend = Mu_fSlip - m*(ReferencePoint_Slip...
                                - Slip_fSlip);
                            Mu_fSlip_Retrend_2 = Mu_fSlip_2 - m*(ReferencePoint_Slip...
                                - Slip_fSlip_2);
                            SlipFitData = [Slip_fSlip, Mu_fSlip, Mu_fSlip_Retrend];
                            SlipFitData_2 = [Slip_fSlip_2, Mu_fSlip_2, Mu_fSlip_Retrend_2];
                        end
                    else
                        SlipFitData = [ ];
                        SlipFitData_2 = [ ];
                    end
            
                    StepData = struct('LoadPointDisplacementData', SlipZoom, 'FrictionData', FrictionZoom,...
                        'FrictionDataDetrended', Friction_Detrend, 'TimeData', TimeZoom,...
                        'NormalStressData', NormalStressZoom, 'DetrendParameters', [],...
                        'FittingOptions', [], 'HoldParameters', [], 'GuessParameters', [],...
                        'AgingLawParameters', [], 'AgingLawParameters2State', [], 'SlipLawParameters', [], 'SlipLawParameters2State', [], 'AgingLawFit',...
                        AgingFitData, 'AgingLaw2State', AgingFitData_2 ,'SlipLawFit', SlipFitData, 'SlipLawFit2State', SlipFitData_2,'WeightInfo', []);
            
                    StepData.HoldParameters = struct('HoldLocation',[HoldSlip, HoldFriction],...
                        'LoadVelocity', v_i, 'ReloadVelocity',...
                        v_f, 'TimeOfHold', TimeOfHold, 'HoldDuration', HoldTime, ...
                        'NormalStress', NormStress);
                end
            
                StepData.DetrendParameters = struct('Point1', [Point1_Slip, Point1_Friction],...
                    'Point2', [Point2_Slip, Point2_Friction], 'ReferencePoint',...
                    [ReferencePoint_Slip, ReferencePoint_Friction], 'Slope', m);
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    OptionE = 'Velocity Step';
                else
                    OptionE = 'Slide-Hold-Slide';
                end
                if StateVarFlag
                    OptionS = 'true';
                else
                    OptionS = 'false';
                end
                if MuFlag
                    OptionMu = 'false';
                else
                    OptionMu = 'true';
                end
                if StiffnessFlag
                    OptionK = 'false';
                else
                    OptionK = 'true';
                end
                if WeightFlag
                    OptionW = 'true';
                else
                    OptionW = 'false';
                end
                StepData.FittingOptions = struct('EventType', OptionE, 'UseTwoStateVariables',...
                    OptionS, 'ConstrainMu_0', OptionMu, 'ConstrainStiffness', OptionK,...
                    'UseWeightFunction', OptionW);
            
                StepData.WeightInfo = struct('Weights', Weight, 'N', WeightParameters(1),...
                    'i_w', WeightParameters(2), 'p', WeightParameters(3), 'WeightLocation',...
                    [WeightSlip, WeightFriction]);
            %%% Get parameter guesses, time of step, and step velocity values.
            %%% RAS fitting parameters.
                mu_iString = get(app.Guess_muValue,'Value');
                mu_i = str2double(mu_iString);
                aString = get(app.Guess_aValue,'Value');
                a = str2double(aString);
                bString = get(app.Guess_bValue,'Value');
                b = str2double(bString);
                d_cString = get(app.Guess_dcValue,'Value');
                d_c = str2double(d_cString);
                kString = get(app.Guess_kValue,'Value');
                k = str2double(kString);
            
                StepData.GuessParameters = struct('mu_0', mu_i, 'a', a, 'b1',...
                    b, 'b2', x_0(6), 'd_c1', d_c, 'd_c2', x_0(7), 'stiffness', k);
            
            if isempty(x_fAging)
                StepData.AgingLawParameters = [ ];
                StepData.AgingLawParameters2State = [ ];
            else
                CovarA = getappdata(app.FitButton,'CovarianceA');
                CovarA_2 = getappdata(app.FitButton,'CovarianceA_2');

                StepData.AgingLawParameters = struct('mu_0', [x_fAging(1), AgingError(1)],...
                    'a', [x_fAging(2), AgingError(2)], 'b1', [x_fAging(3), AgingError(3)],...
                    'b2', [x_fAging(6), AgingError(4)], 'aminusb', [x_fAging(2) - x_fAging(3)...
                    - x_fAging(6), AgingError(8)], 'd_c1', [x_fAging(4), AgingError(5)],...
                    'd_c2', [x_fAging(7), AgingError(6)], 'stiffness', [x_fAging(5),...
                    AgingError(7)], 'ExitFlag', ExitAging, 'R_Squared', FrictionCOD_A,...
                    'CovarianceMatrix', CovarA, 'AIC', app.AgingAIC);
                StepData.AgingLawParameters2State = struct('mu_0', [x_fAging_2(1), AgingError_2(1)],...
                    'a', [x_fAging_2(2), AgingError_2(2)], 'b1', [x_fAging_2(3), AgingError_2(3)],...
                    'b2', [x_fAging_2(6), AgingError_2(4)], 'aminusb', [x_fAging_2(2) - x_fAging_2(3)...
                    - x_fAging_2(6), AgingError_2(8)], 'd_c1', [x_fAging_2(4), AgingError_2(5)],...
                    'd_c2', [x_fAging_2(7), AgingError_2(6)], 'stiffness', [x_fAging_2(5),...
                    AgingError_2(7)], 'ExitFlag', ExitAging_2, 'R_Squared', FrictionCOD_A_2,...
                    'CovarianceMatrix', CovarA_2, 'AIC', app.AgingAIC_2);
            end
            
            if isempty(x_fSlip)
                StepData.SlipLawParameters = [ ];
                StepData.SlipLawParameters2State = [ ];
            else
                CovarS = getappdata(app.FitButton,'CovarianceS');
                CovarS_2 = getappdata(app.FitButton,'CovarianceS_2');
                StepData.SlipLawParameters = struct('mu_0', [x_fSlip(1), SlipError(1)],...
                    'a', [x_fSlip(2), SlipError(2)], 'b1', [x_fSlip(3), SlipError(3)],...
                    'b2', [x_fSlip(6), SlipError(4)], 'aminusb', [x_fSlip(2) - x_fSlip(3)...
                    - x_fSlip(6), SlipError(8)], 'd_c1', [x_fSlip(4), SlipError(5)],...
                    'd_c2', [x_fSlip(7), SlipError(6)], 'stiffness', [x_fSlip(5),...
                    SlipError(7)], 'ExitFlag', ExitSlip, 'R_Squared', FrictionCOD_S,...
                    'CovarianceMatrix', CovarS, 'AIC', app.SlipAIC);
                StepData.SlipLawParameters2State = struct('mu_0', [x_fSlip_2(1), SlipError_2(1)],...
                    'a', [x_fSlip_2(2), SlipError_2(2)], 'b1', [x_fSlip_2(3), SlipError_2(3)],...
                    'b2', [x_fSlip_2(6), SlipError_2(4)], 'aminusb', [x_fSlip_2(2) - x_fSlip_2(3)...
                    - x_fSlip_2(6), SlipError_2(8)], 'd_c1', [x_fSlip_2(4), SlipError_2(5)],...
                    'd_c2', [x_fSlip_2(7), SlipError_2(6)], 'stiffness', [x_fSlip_2(5),...
                    SlipError_2(7)], 'ExitFlag', ExitSlip_2, 'R_Squared', FrictionCOD_S_2,...
                    'CovarianceMatrix', CovarS_2, 'AIC', app.SlipAIC_2);
            end
            
            %%% Save the structure to the workspace.
                StuctureName = get(app.FitTagName,'Value');
                assignin('base',StuctureName,StepData);
                app.FitTagName.Value = '';
        end

        % Button pushed function: DetrendButton
        function DetrendButton_Callback(app, event)
            % --- Executes on button press in DetrendButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DetrendButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Get the slip, friction, normal stress, and time data.
                SlipData = getappdata(app.SlipDataName, 'Slip_Data');
                FrictionData = getappdata(app.FrictionDataName, 'Friction_Data');
                TimeData = getappdata(app.TimeDataName, 'Time_Data');
                NormStressData = getappdata(app.P_cDataName, 'NormStress_Data');
            %%% Determine the slip and friction data from only the visible portion of
            %%% the main axes.
                XLimZoom = get(app.MainAxes,'XLim');
                [~, i] = min(abs(SlipData - XLimZoom(1)));
                [~, ii] = min(abs(SlipData - XLimZoom(2)));
                SlipZoom = SlipData(i:ii);
                FrictionZoom = FrictionData(i:ii);
                TimeZoom = TimeData(i:ii);
                NormStressZoom = NormStressData(i:ii);
            %%% Read linear fit latex_slope value from text box, in case it has been manually
            %%% entered.
                m_String = get(app.SlopeValue,'Value');
                m = str2double(m_String);
            %%% Get the reference slip value for detrending, use the value from the
            %%% picked reference point.
                Slip_Ref_String = get(app.ReferencePointSlipValue, 'Value');
                Slip_Reference = str2double(Slip_Ref_String);
            %%% Detrend, reference to zero, and plot in fitting axes.
                if m == 0
                    Friction_Detrend = FrictionZoom;
                else
                    Friction_Detrend = FrictionZoom + m*(Slip_Reference - SlipZoom);
                end
                Slip_ZeroRef = SlipZoom - SlipZoom(1);
                Time_ZeroRef = TimeZoom - TimeZoom(1);
            %%% Plot detrended data in Fitting Axes. If 'velocity step' is selected in
            %%% the Event Type Panel, plot vs. displacement. If 'slide-hold-slide' is
            %%% selected, plot vs. time.
                if app.EventButtonGroup.SelectedObject == app.VelocityStepButton
                    plot(app.FittingAxes,Slip_ZeroRef,Friction_Detrend,'k.');
                    xlabel(app.FittingAxes,'Load Point Displacement (\mum)');
                    ylabel(app.FittingAxes,'Friction Coefficient');
                else
                    plot(app.FittingAxes,Time_ZeroRef,Friction_Detrend,'k.');
                    xlabel(app.FittingAxes,'Time (s)');
                    ylabel(app.FittingAxes,'Friction Coefficient');
                end
            %%% Store the zero-referenced data.
                setappdata(app.DetrendButton,'FrictionDetrend_Data',Friction_Detrend);
                setappdata(app.DetrendButton,'SlipZeroRef_Data',Slip_ZeroRef);
                setappdata(app.DetrendButton,'TimeZeroRef_Data',Time_ZeroRef);
                setappdata(app.SlopeValue,'SlipZoom_Data',SlipZoom);
                setappdata(app.SlopeValue,'FrictionZoom_Data',FrictionZoom);
                setappdata(app.SlopeValue,'TimeZoom_Data',TimeZoom);
                setappdata(app.SlopeValue, 'NormStressZoomData', NormStressZoom);
        end

        % Selection changed function: EventButtonGroup
        function EventButtonGroup_SelectionChangedFcn(app, event)
            % --- Executes when selected object is changed in EventButtonGroup.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to the selected object in EventButtonGroup
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            str = get(hObject, 'Tag');
            if strcmp(str, 'SHSButton')
                app.VelocityStepPanel.Enable = 'off';
                app.SHSPanel.Enable = 'on';
            else
                app.VelocityStepPanel.Enable = 'on';
                app.SHSPanel.Enable = 'off';
            end
        end

        % Button pushed function: FitButton
        function FitButton_Callback(app, event)
            % --- Executes on button press in FitButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FitButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Clear any previously stored fit data.
                setappdata(app.FitButton,'X0',[ ]);
                setappdata(app.FitButton,'XF_Aging',[ ]);
                setappdata(app.FitButton,'SlipFit_Aging',[ ]);
                setappdata(app.FitButton,'FrictionFit_Aging',[ ]);
                setappdata(app.FitButton,'SampleSlipFit_Aging', [ ]);
                setappdata(app.FitButton,'AgingErrors',[ ]);
                setappdata(app.FitButton,'XF_Slip',[ ]);
                setappdata(app.FitButton,'SlipFit_Slip',[ ]);
                setappdata(app.FitButton,'FrictionFit_Slip',[ ]);
                setappdata(app.FitButton,'SampleSlipFit_Slip', [ ]);
                setappdata(app.FitButton,'SlipErrors',[ ]);
                setappdata(app.FitButton, 'ExitFlag', [ ]);
                setappdata(app.FitButton, 'Residuals', [ ]);

                setappdata(app.FitButton,'X0_2',[ ]);
                setappdata(app.FitButton,'XF_Aging_2',[ ]);
                setappdata(app.FitButton,'SlipFit_Aging_2',[ ]);
                setappdata(app.FitButton,'FrictionFit_Aging_2',[ ]);
                setappdata(app.FitButton,'SampleSlipFit_Aging_2', [ ]);
                setappdata(app.FitButton,'AgingErrors_2',[ ]);
                setappdata(app.FitButton,'XF_Slip_2',[ ]);
                setappdata(app.FitButton,'SlipFit_Slip_2',[ ]);
                setappdata(app.FitButton,'FrictionFit_Slip_2',[ ]);
                setappdata(app.FitButton,'SampleSlipFit_Slip_2', [ ]);
                setappdata(app.FitButton,'SlipErrors_2',[ ]);
                setappdata(app.FitButton, 'ExitFlag_2', [ ]);
                setappdata(app.FitButton, 'Residuals_2', [ ]);
            
                %%% Clear the fit parameters windows.
                set(app.AgingFit_muValue, 'Value', '');
                set(app.AgingFit_aValue, 'Value', '');
                set(app.AgingFit_bValue, 'Value', '');
                set(app.AgingFit_dcValue, 'Value', '');
                set(app.AgingFit_kValue, 'Value', '');
                set(app.AgingRes, 'Value', '');
                set(app.AgingFlag, 'Value', '');
                set(app.SlipFit_muValue, 'Value', '');
                set(app.SlipFit_aValue, 'Value', '');
                set(app.SlipFit_bValue, 'Value', '');
                set(app.SlipFit_dcValue, 'Value', '');
                set(app.SlipFit_kValue, 'Value', '');
                set(app.SlipRes, 'Value','');
                set(app.SlipFlag, 'Value', '');

                set(app.AgingFit_muValue_2, 'Value', '');
                set(app.AgingFit_aValue_2, 'Value', '');
                set(app.AgingFit_bValue_2, 'Value', '');
                set(app.AgingFit_dcValue_2, 'Value', '');
                set(app.AgingFit_kValue_2, 'Value', '');
                set(app.AgingFit_b2Value_2, 'Value', '');
                set(app.AgingFit_dc2Value_2, 'Value', '');
                set(app.AgingRes_2, 'Value', '');
                set(app.AgingFlag_2, 'Value', '');
                set(app.SlipFit_muValue_2, 'Value', '');
                set(app.SlipFit_aValue_2, 'Value', '');
                set(app.SlipFit_bValue_2, 'Value', '');
                set(app.SlipFit_dcValue_2, 'Value', '');
                set(app.SlipFit_kValue_2, 'Value', '');
                set(app.SlipFit_b2Value_2, 'Value', '');
                set(app.SlipFit_dc2Value_2, 'Value', '');
                set(app.SlipRes_2, 'Value','');
                set(app.SlipFlag_2, 'Value', '');
            %%% Clear any previously plotted fits.
            children = get(app.FittingAxes, 'children');
            if numel(children) > 1
                for i = 1:numel(children)
                    if strcmp('-', get(children(i), 'LineStyle'))
                        delete(children(i));
                    end
                end
            end
            
            
            %%% Get the stuff.
                [x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                    StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
                    varargout] = GetStuff(app, handles);
            %%% Check if using sample slip data.
                SampleSlipFlag = getappdata(app.FitButton, 'SampleSlipFlag');
            %%% Check what fits to do.
                AgingLawFlag = getappdata(app.FitButton, 'AgingLawFlag');
                SlipLawFlag = getappdata(app.FitButton, 'SlipLawFlag');
            %%% Store the normal stress.
                setappdata(app.FitButton, 'NormStressStep', NormStress);
            %%% Get the friction data and carry out the inversion.
                Friction_Detrend = getappdata(app.DetrendButton,'FrictionDetrend_Data');
                FrictionVariability = sum((Friction_Detrend - mean(Friction_Detrend)).^2);
            
            % %%% Aging Law Fit
            % if AgingLawFlag == 1
            %     [exitflagA, residualA] = AgingFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
            %         StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
            %         varargout);
            % end
            % 
            % if SlipLawFlag == 1
            %     [exitflagS, residualS] = SlipFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
            %         StateVarFlag,EventFlag,StiffnessFlag,MuFlag,Weight,...
            %         varargout);
            % end
            resList = [];
            flagList = [];
            for test = 1:4
                if test < 3 & AgingLawFlag == 1
                    if test == 1
                        [exitflagA_2, residualA_2] = AgingFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                            1,EventFlag,StiffnessFlag,MuFlag,Weight,...
                            varargout);
                        flagList = [flagList, exitflagA_2];
                        resList = [resList, residualA_2];
                        set(app.AgingFlag_2, 'Value', num2str(exitflagA_2));
                    else
                        [exitflagA, residualA] = AgingFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                            0,EventFlag,StiffnessFlag,MuFlag,Weight,...
                            varargout);
                        flagList = [flagList, exitflagA];
                        resList = [resList, residualA];
                        set(app.AgingFlag, 'Value', num2str(exitflagA));
                    end
                elseif test > 2 & SlipLawFlag == 1
                    if test == 3
                        [exitflagS_2, residualS_2] = SlipFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                                1,EventFlag,StiffnessFlag,MuFlag,Weight,...
                                varargout);
                        flagList = [flagList, exitflagS_2];
                        resList = [resList, residualS_2];
                        set(app.SlipFlag_2, 'Value', num2str(exitflagS_2));
                    else
                        [exitflagS, residualS] = SlipFit(app, x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,...
                                0, EventFlag,StiffnessFlag,MuFlag,Weight,...
                                varargout);
                        flagList = [flagList, exitflagS];
                        resList = [resList, residualS];
                        set(app.SlipFlag, 'Value', num2str(exitflagS));
                    end
                end
            end
            setappdata(app.FitButton, 'ExitFlag', flagList);
            setappdata(app.FitButton, 'Residuals', resList);
            % %%% Store and display the exit flags.
            %     if AgingLawFlag == 1 & SlipLawFlag == 1
            %         setappdata(app.FitButton, 'ExitFlag', [exitflagA; exitflagS]);
            %         setappdata(app.FitButton, 'Residuals', [residualA; residualS]);
            %         set(app.AgingFlag, 'Value', num2str(exitflagA));
            %         set(app.SlipFlag, 'Value', num2str(exitflagS));
            %     elseif AgingLawFlag == 1
            %         setappdata(app.FitButton, 'ExitFlag', exitflagA);
            %         setappdata(app.FitButton, 'Residuals', residualA);
            %         set(app.AgingFlag, 'Value', num2str(exitflagA));
            %     elseif SlipLawFlag == 1
            %         setappdata(app.FitButton, 'ExitFlag', exitflagS);
            %         setappdata(app.FitButton, 'Residuals', residualS);
            %         set(app.SlipFlag, 'Value', num2str(exitflagS));
            %     end
        end

        % Button pushed function: FitTrendButton
        function FitTrendButton_Callback(app, event)
            % --- Executes on button press in FitTrendButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FitTrendButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Get the point locations.
                Point1_SlipString = get(app.Point1SlipValue,'Value');
                Point2_SlipString = get(app.Point2SlipValue,'Value');
                Point1_Slip = str2double(Point1_SlipString);
                Point2_Slip = str2double(Point2_SlipString);
            %%% Get the slip, friction, and time data.
                SlipData = getappdata(app.SlipDataName,'Slip_Data');
                FrictionData = getappdata(app.FrictionDataName,'Friction_Data');
                TimeData = getappdata(app.TimeDataName,'Time_Data');
            %%% Determine the slip and friction data from only the visible portion of
            %%% the main axes.
                XLimZoom = get(app.MainAxes,'XLim');
                [~, i] = min(abs(SlipData - XLimZoom(1)));
                [~, ii] = min(abs(SlipData - XLimZoom(2)));
                SlipZoom = SlipData(i:ii);
                FrictionZoom = FrictionData(i:ii);
                TimeZoom = TimeData(i:ii);
            %%% Determine the section of the data to fit the linear trend from the
            %%% locations of the set points.
                [~, i] = min(abs(SlipZoom - Point1_Slip));
                [~, ii] = min(abs(SlipZoom - Point2_Slip));
            %%% See if there is a previous detrend line plotted and delete it.
                children = get(app.MainAxes, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Do the linear fit and plot the line on the main axes.
                p = polyfit(SlipZoom(i:ii),FrictionZoom(i:ii),1);
                hold(app.MainAxes,'on');
                plot(app.MainAxes,SlipZoom,p(1)*SlipZoom + p(2),'r')
                hold(app.MainAxes,'off');
            %%% Display the latex_slope value in the GUI latex_slope box.
                set(app.SlopeValue, 'Value', num2str(p(1)));
            %%% Store the zoomed slip and friction values, and the linear fit latex_slope
            %%% value.
                setappdata(app.SlopeValue,'SlipZoom_Data',SlipZoom);
                setappdata(app.SlopeValue,'FrictionZoom_Data',FrictionZoom);
                setappdata(app.SlopeValue,'TimeZoom_Data',TimeZoom);
                setappdata(app.SlopeValue,'Slope_Value',p(1));
        end

        % Button down function: MainAxes
        function MainAxes_ButtonDownFcn(app, event)
            % --- Executes on mouse press over axes background.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to MainAxes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% See if there's a previous box plotted and delete it.
                children = get(app.StaticAxes, 'children');
                if numel(children) > 1
                    delete(children(1:end-1));
                end
            %%% Plot outline box on static axes when limits of main axes are changed.
                XLimZoom = get(app.MainAxes,'XLim');
                YLimZoom = get(app.MainAxes,'YLim');
                hold(app.StaticAxes, 'on');
                plot(app.StaticAxes,XLimZoom,YLimZoom(1)*[1 1],'r','LineWidth',2)
                plot(app.StaticAxes,XLimZoom,YLimZoom(2)*[1 1],'r','LineWidth',2)
                plot(app.StaticAxes,XLimZoom(1)*[1 1],YLimZoom,'r','LineWidth',2)
                plot(app.StaticAxes,XLimZoom(2)*[1 1],YLimZoom,'r','LineWidth',2)
        end

        % Button pushed function: PlotButton
        function PlotButton_Callback(app, event)
            % --- Executes on button press in PlotButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to PlotButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Get the data from the workspace.
                SlipDataString = get(app.SlipDataName, 'Value');
                SlipData = evalin('base', SlipDataString);
                FrictionDataString = get(app.FrictionDataName, 'Value');
                FrictionData = evalin('base', FrictionDataString);
                TimeDataString = get(app.TimeDataName, 'Value');
                TimeData = evalin('base', TimeDataString);
                NormStressDataString = get(app.P_cDataName, 'Value');
                NormStressData = evalin('base', NormStressDataString);
            %%% Check if using sample slip data.
                SampleSlipFlag = getappdata(app.FitButton, 'SampleSlipFlag');
                if SampleSlipFlag
                    SampleSlipDataString = get(app.SampleSlipDataName, 'Value');
                    SampleSlipData = evalin('base', SampleSlipDataString);
                    setappdata(app.SampleSlipDataName, 'SampleSlip_Data', SampleSlipData);
                end
            %%% Plot the slip and friction data on the main axes and the static axes.
                plot(app.MainAxes, SlipData, FrictionData, 'k.');
                set(app.MainAxes, 'ButtonDownFcn', {@MainAxes_ButtonDownFcn, handles},...
                    'HitTest', 'on');
                xlabel(app.MainAxes,'Load Point Displacement (\mum)');
                ylabel(app.MainAxes,'Friction Coefficient');
                Main_ylim = ylim(app.MainAxes);
                if Main_ylim(1) < 0
                    Main_ylim = [0 Main_ylim(2)];
                    ylim(app.MainAxes,Main_ylim);
                end
                children = get(app.StaticAxes, 'children');
                delete(children);
                hold(app.StaticAxes, 'off');
                plot(app.StaticAxes,SlipData,FrictionData,'k.');
                hold(app.StaticAxes, 'on');
                xlabel(app.StaticAxes,'Load Point Displacement (\mum)');
                ylabel(app.StaticAxes,'Friction Coefficient');
            	ylim(app.StaticAxes,Main_ylim);
            %%% Store the data for use in other callback functions.
                setappdata(app.SlipDataName, 'Slip_Data', SlipData);
                setappdata(app.FrictionDataName, 'Friction_Data', FrictionData);
                setappdata(app.TimeDataName, 'Time_Data', TimeData);
                setappdata(app.P_cDataName, 'NormStress_Data', NormStressData);
        end

        % Button pushed function: SaveWorkspaceButton
        function SaveWorkspaceButton_Callback(app, event)
            % --- Executes on button press in SaveWorkspaceButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SaveWorkspaceButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Get the filename and save the entire workspace.
                WorkspaceName = get(app.WorkspaceName,'Value');
                evalin('base', sprintf('save(''%s'')', WorkspaceName));
        end

        % Button pushed function: SetHoldButton
        function SetHoldButton_Callback(app, event)
           %%% Plot the detrended friction data against zero referenced time.
           Time_ZeroRef = getappdata(app.DetrendButton,'TimeZeroRef_Data');
           Slip_ZeroRef = getappdata(app.DetrendButton,'SlipZeroRef_Data');
           %%% Set the data point to be recorded when the left mouse button is
           %%% clicked.
           datacursormode on
            waitforbuttonpress
            cursorMode = datacursormode(gcf);
            dcm_Info = getCursorInfo(cursorMode);
            Position = dcm_Info.Position;
            datacursormode off
        %%% Find the zero reference slip value corresponding to the selected time.
            [~, ii] = min(abs(Time_ZeroRef - Position(1)));
            HoldSlip = Slip_ZeroRef(ii);
        %%% Plot the displacement time series for the windowed data, showing the
        %%% location of the selected hold
            figure;plot(Time_ZeroRef, Slip_ZeroRef, 'k.', Position(1), Slip_ZeroRef(ii),...
                'ro','MarkerFaceColor','r')
            xlabel('Time (s)')
            ylabel('Load Point Displacement (\mum)')      
        %%% Display the values in the SHS Parameters panel    
            set(app.HoldSlipValue, 'Value', num2str(Position(1)));
            set(app.HoldFrictionValue, 'Value', num2str(Position(2)));
        %%% Store values and the plot handle.
            setappdata(app.SetHoldButton,'Step_TimeValue',Position(1));
            setappdata(app.HoldSlipValue,'Step_SlipValue',HoldSlip);
            setappdata(app.HoldFrictionValue,'Step_FrictionValue',Position(2));
        %%% Find the normal stress at time of step an display.
            NormStressZoom = getappdata(app.SlopeValue, 'NormStressZoomData');
            NormStress = NormStressZoom(ii);
            set(app.LocNormStress, 'Value', num2str(NormStress));
        end

        % Button pushed function: SetPoint1Button
        function SetPoint1Button_Callback(app, event)
            % --- Executes on button press in SetPoint1Button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SetPoint1Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
            
            % HERE FOR CLICKING
                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.Point1SlipValue, 'Value', num2str(Position(1)));
                set(app.Point1MuValue, 'Value', num2str(Position(2)));
        end

        % Button pushed function: SetPoint2Button
        function SetPoint2Button_Callback(app, event)
            % --- Executes on button press in SetPoint2Button.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SetPoint2Button (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.Point2SlipValue, 'Value', num2str(Position(1)));
                set(app.Point2MuValue, 'Value', num2str(Position(2)));
        end

        % Button pushed function: SetReferencePointButton
        function SetReferencePointButton_Callback(app, event)
            % --- Executes on button press in SetReferencePointButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SetReferencePointButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.ReferencePointSlipValue, 'Value', num2str(Position(1)));
                set(app.ReferencePointMuValue, 'Value', num2str(Position(2)));
            %%% Store slip value for detrending later.
                setappdata(app.ReferencePointSlipValue,'ReferencePoint_SlipValue',Position(1));
        end

        % Button pushed function: SetVelocityStepButton
        function SetVelocityStepButton_Callback(app, event)
            % --- Executes on button press in SetVelocityStepButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SetVelocityStepButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.StepSlipValue, 'Value', num2str(Position(1)));
                set(app.StepFrictionValue, 'Value', num2str(Position(2)));
                set(app.Guess_muValue, 'Value', num2str(Position(2)));
            %%% Store slip value for detrending later.
                setappdata(app.StepSlipValue,'Step_SlipValue',Position(1));
                setappdata(app.StepFrictionValue,'Step_SlipValue',Position(2));
            %%% Compute sliding velocities before and after the step, using the
            %%% windowed data.
                Slip_ZeroRef = getappdata(app.DetrendButton,'SlipZeroRef_Data');
                Time_ZeroRef = getappdata(app.DetrendButton,'TimeZeroRef_Data');
                [~, kk] = min(abs(Slip_ZeroRef - Position(1)));
                v_i = Slip_ZeroRef(kk)/Time_ZeroRef(kk);
                v_f = (Slip_ZeroRef(end) - Slip_ZeroRef(kk))...
                    /(Time_ZeroRef(end) - Time_ZeroRef(kk));
            %%% Plot the displacement time series for the windowed data, showing the
            %%% location of the selected step.
                figure;plot(Time_ZeroRef, Slip_ZeroRef, 'k.', Time_ZeroRef(kk),Position(1),...
                    'ro','MarkerFaceColor','r')
                xlabel('Time (s)')
                ylabel('Load Point Displacement (\mum)')
            %%% Store velocity values and display in the GUI.
                set(app.InitialVelocityValue,'Value', num2str(v_i));
                set(app.FinalVelocityValue,'Value', num2str(v_f));
            %%% Find the normal stress at time of step an display.
                NormStressZoom = getappdata(app.SlopeValue, 'NormStressZoomData');
                NormStress = NormStressZoom(kk);
                set(app.LocNormStress, 'Value', num2str(NormStress));
        end

        % Button pushed function: TestButton
        function TestButton_Callback(app, event)
            % --- Executes on button press in TestButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to TestButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% See if there's any previous lines plotted and delete them.
            children = get(app.FittingAxes, 'children');
            if numel(children) > 1
                delete(children(1:end-1));
            end
            
            %%% Get the stuff.
            [x_0,x_e,v_i,v_f,NormStress,TimeOfStep,Slip_ZeroRef,Time_ZeroRef,StateVarFlag,...
                EventFlag,StiffnessFlag,MuFlag,Weight,Holds]...
                = GetStuff(app, handles);
            a = x_0(2);
            b1 = x_0(3);
            d_c1 = x_0(4);
            b2 = x_0(6);
            d_c2 = x_0(7);
            if MuFlag == true
                mu_0 = x_0(1);
            else
                mu_0 = x_e(1);
            end
            %%% Run a spring-slider simulation using the guessed parameters.
            state1_i = d_c1/v_i;
            if StateVarFlag
                state2_i = d_c2/v_i;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1)...
                    + b2*log(v_i*state2_i/d_c2);
            else
                state2_i = 0;
                mu_i = mu_0 + a*log(v_i/v_i) + b1*log(v_i*state1_i/d_c1);
            end
            sigma_i = NormStress;
            tau_i = mu_i*sigma_i;
            slip_i = Slip_ZeroRef(1);
            Vars_i = [state1_i; state2_i; v_i; mu_i; slip_i; sigma_i; tau_i];
            opt = odeset('RelTol',1e-9,'AbsTol',[1e-12 1e-12 1e-32 1e-12 1e-12 1e-12 1e-12]);
            
            %%% Check what fits to do.
            AgingLawFlag = getappdata(app.FitButton, 'AgingLawFlag');
            SlipLawFlag = getappdata(app.FitButton, 'SlipLawFlag');
            legend(app.FittingAxes,'-DynamicLegend');
            hold(app.FittingAxes, 'on');
            for test = 1:4
                if test < 3 & AgingLawFlag == 1
                    if mod(test, 2) == 0
                        TwoState = false;
                        AgingTest(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                                TwoState, EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i, opt);
                    elseif mod(test, 2) == 1 & StateVarFlag == 1
                        TwoState = true;
                        AgingTest(app,x_0,x_e,v_i,v_f,TimeOfStep,...
                                TwoState,EventFlag,StiffnessFlag,MuFlag, Holds, Time_ZeroRef, Vars_i, opt);
                    end
                elseif test > 2 & SlipLawFlag == 1
                    if mod(test, 2) == 0
                        TwoState = false;
                        SlipTest(app, x_0,x_e,v_i,v_f,TimeOfStep,...
                                false,EventFlag,StiffnessFlag,MuFlag,Holds, Time_ZeroRef, Vars_i, opt);
                    elseif mod(test, 2) == 1 & StateVarFlag == 1
                        TwoState = true;
                        SlipTest(app, x_0,x_e,v_i,v_f,TimeOfStep,...
                                true,EventFlag,StiffnessFlag,MuFlag,Holds, Time_ZeroRef, Vars_i, opt);
                    end
                end
            end
            hold(app.FittingAxes, 'off');
        end

        % Button pushed function: UpdateListboxButton
        function UpdateListboxButton_Callback(app, event)
            % --- Executes on button press in UpdateListboxButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to UpdateListboxButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            update_listbox(app, handles)
        end

        % Button pushed function: WeightLocButton
        function WeightLocButton_Callback(app, event)
            % --- Executes on button press in WeightLocButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to WeightLocButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            
            %%% Set the data point to be recorded when the left mouse button is
            %%% clicked.
                datacursormode on
                waitforbuttonpress
                cursorMode = datacursormode(gcf);
                dcm_Info = getCursorInfo(cursorMode);
                Position = dcm_Info.Position;
                datacursormode off
                set(app.WeightSlipValue, 'Value', num2str(Position(1)));
                set(app.WeightFrictionValue, 'Value', num2str(Position(2)));
            %%% Store slip value for detrending later.
                setappdata(app.WeightSlipValue,'Weight_SlipValue',Position(1));
                setappdata(app.WeightFrictionValue,'Weight_SlipValue',Position(2));
            %%% Calculate the weights and plot in a new window.
                Slip_ZeroRef = getappdata(app.DetrendButton,'SlipZeroRef_Data');
                [Weight, WeightInfo] = WeightCalc(app, handles);
                figure;
                semilogy(Slip_ZeroRef, Weight, 'k', 'LineWidth', 2);
                xlabel('Load Point Displacement (\mum)');
                ylabel('Weight (N / i_w)^p');
                title(['N = ', num2str(WeightInfo(1)), ' total data points, i_w = ', num2str(WeightInfo(2)),...
                    ', p = ', num2str(WeightInfo(3))]);
        end

        % On callback: uitoggletool1
        function uitoggletool1_OnCallback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitoggletool1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            zoom_handle = zoom(app.MainAxes);
            zoom_handle.ActionPostCallback = @app.MainAxPostCallback;
        end

        % On callback: uitoggletool3
        function uitoggletool3_OnCallback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to uitoggletool3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app    structure with app and user data (see GUIDATA)
            pan_handle = pan(app.RASGUI);
            pan_handle.ActionPostCallback = @app.MainAxPostCallback;
        end

        % Callback function: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(app.RASGUI, char(state));
        end

        % Callback function: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(app.RASGUI, char(state));
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.RASGUI);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(app.RASGUI);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Value changed function: WeightButton
        function WeightButtonValueChanged(app, event)
            value = app.WeightButton.Value;
            if value
                setappdata(app.FitButton, 'WeightFlag', true);
                set(findall(app.WeightPanel, '-property', 'Enable'), 'Enable', 'on')
            else
                setappdata(app.FitButton, 'WeightFlag', false);
                set(findall(app.WeightPanel, '-property', 'Enable'),...
                'Enable', 'off')
            end
        end

        % Value changed function: MuButton
        function MuButtonValueChanged(app, event)
            value = app.MuButton.Value;
            AgingLawFlag = getappdata(app.FitButton, 'AgingLawFlag');
            SlipLawFlag = getappdata(app.FitButton, 'SlipLawFlag');
            StateVarFlag = getappdata(app.FitButton, 'StateVarFlag');
            if value
                setappdata(app.FitButton, 'MuFlag', false);
                set(app.AgingFit_muValue, 'Enable', 'off');
                set(app.SlipFit_muValue, 'Enable', 'off');
                if StateVarFlag
                    set(app.AgingFit_muValue_2, 'Enable', 'off');
                    set(app.SlipFit_muValue_2, 'Enable', 'off');
                end
            else
                %%% Button is not selected, disable the alpha variable field, and set the 
                %%% MuFlag to "false".
                setappdata(app.FitButton, 'MuFlag', true);
                if AgingLawFlag
                    set(app.AgingFit_muValue, 'Enable', 'on')
                    if StateVarFlag
                        set(app.AgingFit_muValue_2, 'Enable', 'on');
                    end
                end
                if SlipLawFlag
                    set(app.SlipFit_muValue, 'Enable', 'on')
                    if StateVarFlag
                        set(app.SlipFit_muValue_2, 'Enable', 'on');
                    end
                end
            end
        end

        % Callback function
        function TwoStateButtonValueChanged(app, event)
            value = app.TwoStateButton.Value;
            AgingLawFlag = getappdata(app.FitButton, 'AgingLawFlag');
            SlipLawFlag = getappdata(app.FitButton, 'SlipLawFlag');
            MuFlag = getappdata(app.FitButton, 'MuFlag');
            StiffnessFlag = getappdata(app.FitButton, 'StiffnessFlag');
            if value
            %%% Button is selected, enable the additional state variable fields, and
            %%% set the two state variable flag to "true".
                setappdata(app.FitButton, 'StateVarFlag', true);
                set(app.Guess_b2Value, 'Enable', 'on')
                set(app.Guess_dc2Value, 'Enable', 'on')
                if AgingLawFlag
                    set(app.AgingFit_b2Value_2, 'Enable', 'on')
                    set(app.AgingFit_dc2Value_2, 'Enable', 'on')
                    if MuFlag
                        set(app.AgingFit_muValue_2, 'Enable', 'on')
                    end
                    if StiffnessFlag
                        set(app.AgingFit_kValue_2, 'Enable', 'on')
                    end
                    set(app.AgingFit_dcValue_2, 'Enable', 'on')
                    set(app.AgingFit_bValue_2, 'Enable', 'on')
                    set(app.AgingFit_aValue_2, 'Enable', 'on')
                    set(app.AgingRes_2, 'Enable', 'on');
                    set(app.AgingFlag_2, 'Enable', 'on');
                    set(app.AgingAIC_2, 'Enable', 'on');
                end
                if SlipLawFlag
                    set(app.SlipFit_b2Value_2, 'Enable', 'on')
                    set(app.SlipFit_dc2Value_2, 'Enable', 'on')
                    if MuFlag
                        set(app.SlipFit_muValue_2, 'Enable', 'on')
                    end
                    if StiffnessFlag
                        set(app.SlipFit_kValue_2, 'Enable', 'on')
                    end
                    set(app.SlipFit_dcValue_2, 'Enable', 'on')
                    set(app.SlipFit_bValue_2, 'Enable', 'on')
                    set(app.SlipFit_aValue_2, 'Enable', 'on')
                    set(app.SlipRes_2, 'Enable', 'on');
                    set(app.SlipFlag_2, 'Enable', 'on');
                    set(app.SlipAIC_2, 'Enable', 'on');
                end
            else
            %%% Button is not selected, disable the additional state variable fields,
            %%% and set the two state variable flag to "false".
                setappdata(app.FitButton, 'StateVarFlag', false);
                set(app.Guess_b2Value, 'Enable', 'off')
                set(app.Guess_dc2Value, 'Enable', 'off')
                set(app.SlipFit_b2Value_2, 'Enable', 'off')
                set(app.SlipFit_dc2Value_2, 'Enable', 'off')
                set(app.SlipFit_muValue_2, 'Enable', 'off')
                set(app.SlipFit_kValue_2, 'Enable', 'off')
                set(app.SlipFit_dcValue_2, 'Enable', 'off')
                set(app.SlipFit_bValue_2, 'Enable', 'off')
                set(app.SlipFit_aValue_2, 'Enable', 'off')
                set(app.SlipRes_2, 'Enable', 'off');
                set(app.SlipFlag_2, 'Enable', 'off');
                set(app.SlipAIC_2, 'Enable', 'off');
                set(app.AgingFit_b2Value_2, 'Enable', 'off')
                set(app.AgingFit_dc2Value_2, 'Enable', 'off')
                set(app.AgingFit_muValue_2, 'Enable', 'off')
                set(app.AgingFit_kValue_2, 'Enable', 'off')
                set(app.AgingFit_dcValue_2, 'Enable', 'off')
                set(app.AgingFit_bValue_2, 'Enable', 'off')
                set(app.AgingFit_aValue_2, 'Enable', 'off')
                set(app.AgingRes_2, 'Enable', 'off');
                set(app.AgingFlag_2, 'Enable', 'off');
                set(app.AgingAIC_2, 'Enable', 'off');

            end
        end

        % Value changed function: AgingButton
        function AgingButtonValueChanged(app, event)
            value = app.AgingButton.Value;
            StateVarFlag = getappdata(app.FitButton, 'StateVarFlag');
            MuFlag = getappdata(app.FitButton, 'MuFlag');
            StiffnessFlag = getappdata(app.FitButton, 'StiffnessFlag');
            if value
                setappdata(app.FitButton, 'AgingLawFlag', true);
                set(app.AgingFit_aValue, 'Enable', 'on');
                set(app.AgingFit_bValue, 'Enable', 'on');    
                set(app.AgingFit_dcValue, 'Enable', 'on');    
                set(app.AgingRes, 'Enable', 'on');
                set(app.AgingFlag, 'Enable', 'on');
                set(app.AgingAIC, 'Enable', 'on');
                if StateVarFlag
                    set(app.AgingFit_b2Value_2, 'Enable', 'on');
                    set(app.AgingFit_dc2Value_2, 'Enable', 'on');
                    set(app.AgingRes_2, 'Enable', 'on');
                    set(app.AgingFlag_2, 'Enable', 'on');
                    set(app.AgingFit_aValue_2, 'Enable', 'on');
                    set(app.AgingFit_bValue_2, 'Enable', 'on');    
                    set(app.AgingFit_dcValue_2, 'Enable', 'on'); 
                    set(app.AgingAIC_2, 'Enable', 'on');
                end
                if StiffnessFlag
                    set(app.AgingFit_kValue, 'Enable', 'on');
                    if StateVarFlag
                        set(app.AgingFit_kValue_2, 'Enable', 'on');
                    end
                end
                if MuFlag
                    set(app.AgingFit_muValue, 'Enable', 'on');
                    if StateVarFlag
                        set(app.AgingFit_muValue_2, 'Enable', 'on');
                    end
                end
            else
            %%% Button is not selected, disable the aging law data fields, and set the
            %%% AgingLawFlag to "false".
                setappdata(app.FitButton, 'AgingLawFlag', false);
                set(app.AgingFit_muValue, 'Enable', 'off');
                set(app.AgingFit_aValue, 'Enable', 'off');
                set(app.AgingFit_bValue, 'Enable', 'off');
                set(app.AgingFit_dcValue, 'Enable', 'off');
                set(app.AgingFit_kValue, 'Enable', 'off');
                set(app.AgingRes, 'Enable', 'off');
                set(app.AgingFlag, 'Enable', 'off');
                set(app.AgingAIC, 'Enable', 'off');
                set(app.AgingFit_muValue_2, 'Enable', 'off');
                set(app.AgingFit_aValue_2, 'Enable', 'off');
                set(app.AgingFit_bValue_2, 'Enable', 'off');
                set(app.AgingFit_b2Value_2, 'Enable', 'off');
                set(app.AgingFit_dcValue_2, 'Enable', 'off');
                set(app.AgingFit_dc2Value_2, 'Enable', 'off');
                set(app.AgingFit_kValue_2, 'Enable', 'off');
                set(app.AgingRes_2, 'Enable', 'off');
                set(app.AgingFlag_2, 'Enable', 'off');
                set(app.AgingAIC_2, 'Enable', 'off');
            end
        end

        % Value changed function: SlipButton
        function SlipButtonValueChanged(app, event)
            value = app.SlipButton.Value;
            StateVarFlag = getappdata(app.FitButton, 'StateVarFlag');
            MuFlag = getappdata(app.FitButton, 'MuFlag');
            StiffnessFlag = getappdata(app.FitButton, 'StiffnessFlag');
            if value
                setappdata(app.FitButton, 'SlipLawFlag', true);
                set(app.SlipFit_aValue, 'Enable', 'on');
                set(app.SlipFit_bValue, 'Enable', 'on');    
                set(app.SlipFit_dcValue, 'Enable', 'on');    
                set(app.SlipRes, 'Enable', 'on');
                set(app.SlipFlag, 'Enable', 'on');
                set(app.SlipAIC, 'Enable', 'on')
                if StateVarFlag
                    set(app.SlipFit_b2Value_2, 'Enable', 'on');
                    set(app.SlipFit_dc2Value_2, 'Enable', 'on');
                    set(app.SlipAIC_2, 'Enable', 'on');
                    set(app.SlipFit_aValue_2, 'Enable', 'on');
                    set(app.SlipFit_bValue_2, 'Enable', 'on');    
                    set(app.SlipFit_dcValue_2, 'Enable', 'on');    
                    set(app.SlipRes_2, 'Enable', 'on');
                    set(app.SlipFlag_2, 'Enable', 'on');
                end
                if StiffnessFlag
                    set(app.SlipFit_kValue, 'Enable', 'on');
                    if StateVarFlag
                        set(app.SlipFit_kValue_2, 'Enable', 'on');
                    end
                end
                if MuFlag
                    set(app.SlipFit_muValue, 'Enable', 'on');
                    if StateVarFlag
                        set(app.SlipFit_muValue_2, 'Enable', 'on');
                    end
                end
            else
            %%% Button is not selected, disable the sample slip data field, and set the
            %%% SampleSlipFlag to "false".
                setappdata(app.FitButton, 'SampleSlipFlag', false);
                set(app.SlipFit_muValue, 'Enable', 'off');
                set(app.SlipFit_aValue, 'Enable', 'off');
                set(app.SlipFit_bValue, 'Enable', 'off');
                set(app.SlipFit_b2Value_2, 'Enable', 'off');
                set(app.SlipFit_dcValue, 'Enable', 'off');
                set(app.SlipFit_dc2Value_2, 'Enable', 'off');
                set(app.SlipFit_kValue, 'Enable', 'off');
                set(app.SlipFit_kValue_2, 'Enable', 'off');
                set(app.SlipRes, 'Enable', 'off');
                set(app.SlipFlag, 'Enable', 'off');
                set(app.SlipAIC, 'Enable', 'off');
                set(app.SlipAIC_2, 'Enable', 'off');
                set(app.SlipFit_muValue_2, 'Enable', 'off');
                set(app.SlipFit_aValue_2, 'Enable', 'off');
                set(app.SlipFit_bValue_2, 'Enable', 'off');    
                set(app.SlipFit_dcValue_2, 'Enable', 'off');    
                set(app.SlipRes_2, 'Enable', 'off');
                set(app.SlipFlag_2, 'Enable', 'off');
            end
        end

        % Value changed function: StiffnessButton
        function StiffnessButtonValueChanged(app, event)
            value = app.StiffnessButton.Value;
            AgingLawFlag = getappdata(app.FitButton, 'AgingLawFlag');
            SlipLawFlag = getappdata(app.FitButton, 'SlipLawFlag');
            StateVarFlag = getappdata(app.FitButton, 'StateVarFlag');
            if value
                %%% Button is selected, enable the alpha variable field, and set the
                %%% StiffnessFlag to "false".
                    setappdata(app.FitButton, 'StiffnessFlag', false);
                    set(app.AgingFit_kValue, 'Enable', 'off');
                    set(app.SlipFit_kValue, 'Enable', 'off');
                    if StateVarFlag
                        set(app.AgingFit_kValue_2, 'Enable', 'off');
                        set(app.SlipFit_kValue_2, 'Enable', 'off');
                    end
            else
            %%% Button is not selected, disable the alpha variable field, and set the 
            %%% StiffnessFlag to "true".
                setappdata(app.FitButton, 'StiffnessFlag', true);
                if AgingLawFlag
                    set(app.AgingFit_kValue, 'Enable', 'on');
                    if StateVarFlag
                        set(app.AgingFit_kValue_2, 'Enable', 'on');
                    end
                end
                if SlipLawFlag
                    set(app.SlipFit_kValue, 'Enable', 'on')
                    if StateVarFlag
                        set(app.SlipFit_kValue_2, 'Enable', 'on');
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RASGUI = uifigure('Visible', 'off');
            app.RASGUI.Name = 'RSFit3000';
            app.RASGUI.Tag = 'RASGUI';
            app.uitoolbar1 = uitoolbar(app.RASGUI);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool1.OnCallback = createCallbackFcn(app, @uitoggletool1_OnCallback, true);
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool3.OnCallback = createCallbackFcn(app, @uitoggletool3_OnCallback, true);
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Data Cursor';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.FittingAxes = uiaxes(app.RASGUI);
            app.FittingAxes.Tag = 'FittingAxes';
            app.StaticAxes = uiaxes(app.RASGUI);
            app.StaticAxes.Tag = 'StaticAxes';
            app.LogoAxes = uiaxes(app.RASGUI);
            app.LogoAxes.Tag = 'LogoAxes';
            app.MainAxes = uiaxes(app.RASGUI);
            app.MainAxes.ButtonDownFcn = createCallbackFcn(app, @MainAxes_ButtonDownFcn, true);
            app.MainAxes.Tag = 'MainAxes';
            app.DetrendPanel = uipanel(app.RASGUI);
            app.DetrendPanel.Title = 'Detrending Parameters';
            app.DetrendPanel.Tag = 'DetrendPanel';
            app.Point1SlipValue = uieditfield(app.DetrendPanel, 'text');
            app.Point1SlipValue.Tag = 'Point1SlipValue';
            app.DetrendButton = uibutton(app.DetrendPanel, 'push');
            app.DetrendButton.ButtonPushedFcn = createCallbackFcn(app, @DetrendButton_Callback, true);
            app.DetrendButton.Tag = 'DetrendButton';
            app.DetrendButton.Text = 'Detrend';
            app.SlopeValue = uieditfield(app.DetrendPanel, 'text');
            app.SlopeValue.Tag = 'SlopeValue';
            app.latex_Slope = uilabel(app.DetrendPanel);
            app.latex_Slope.Tag = 'latex_Slope';
            app.latex_Slope.Text = 'slope (1 / μm)';
            app.Point2SlipValue = uieditfield(app.DetrendPanel, 'text');
            app.Point2SlipValue.Tag = 'Point2SlipValue';
            app.Point1MuValue = uieditfield(app.DetrendPanel, 'text');
            app.Point1MuValue.Tag = 'Point1MuValue';
            app.Point2MuValue = uieditfield(app.DetrendPanel, 'text');
            app.Point2MuValue.Tag = 'Point2MuValue';
            app.SetPoint1Button = uibutton(app.DetrendPanel, 'push');
            app.SetPoint1Button.ButtonPushedFcn = createCallbackFcn(app, @SetPoint1Button_Callback, true);
            app.SetPoint1Button.Tag = 'SetPoint1Button';
            app.SetPoint1Button.Text = 'Set Point 1';
            app.FitTrendButton = uibutton(app.DetrendPanel, 'push');
            app.FitTrendButton.ButtonPushedFcn = createCallbackFcn(app, @FitTrendButton_Callback, true);
            app.FitTrendButton.Tag = 'FitTrendButton';
            app.FitTrendButton.Text = 'Fit Trend';
            app.text47 = uilabel(app.DetrendPanel);
            app.text47.Tag = 'text47';
            app.text47.Text = 'friction';
            app.text48 = uilabel(app.DetrendPanel);
            app.text48.Tag = 'text48';
            app.text48.Text = 'displacement';
            app.text49 = uilabel(app.DetrendPanel);
            app.text49.Tag = 'text49';
            app.text49.Text = 'friction';
            app.text50 = uilabel(app.DetrendPanel);
            app.text50.Tag = 'text50';
            app.text50.Text = 'displacement';
            app.SetPoint2Button = uibutton(app.DetrendPanel, 'push');
            app.SetPoint2Button.ButtonPushedFcn = createCallbackFcn(app, @SetPoint2Button_Callback, true);
            app.SetPoint2Button.Tag = 'SetPoint2Button';
            app.SetPoint2Button.Text = 'Set Point 2';
            app.text55 = uilabel(app.DetrendPanel);
            app.text55.Tag = 'text55';
            app.text55.Text = {'Set slope to zero'; 'for no trend and'; 'clear points'};
            app.ReferencePointSlipValue = uieditfield(app.DetrendPanel, 'text');
            app.ReferencePointSlipValue.Tag = 'ReferencePointSlipValue';
            app.ReferencePointMuValue = uieditfield(app.DetrendPanel, 'text');
            app.ReferencePointMuValue.Tag = 'ReferencePointMuValue';
            app.text63 = uilabel(app.DetrendPanel);
            app.text63.Tag = 'text63';
            app.text63.Text = 'friction';
            app.text64 = uilabel(app.DetrendPanel);
            app.text64.Tag = 'text64';
            app.text64.Text = 'displacement';
            app.SetReferencePointButton = uibutton(app.DetrendPanel, 'push');
            app.SetReferencePointButton.ButtonPushedFcn = createCallbackFcn(app, @SetReferencePointButton_Callback, true);
            app.SetReferencePointButton.Tag = 'SetReferencePointButton';
            app.SetReferencePointButton.Text = 'Set Reference Point';
            app.ClearPointsButton = uibutton(app.DetrendPanel, 'push');
            app.ClearPointsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPointsButton_Callback, true);
            app.ClearPointsButton.Tag = 'ClearPointsButton';
            app.ClearPointsButton.Text = 'Clear Points';
            app.DataPanel = uipanel(app.RASGUI);
            app.DataPanel.Title = 'Experiment Data';
            app.DataPanel.Tag = 'DataPanel';
            app.PlotButton = uibutton(app.DataPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButton_Callback, true);
            app.PlotButton.Tag = 'PlotButton';
            app.PlotButton.Text = 'Plot';
            app.SlipDataName = uieditfield(app.DataPanel, 'text');
            app.SlipDataName.Tag = 'SlipDataName';
            app.FrictionDataName = uieditfield(app.DataPanel, 'text');
            app.FrictionDataName.Tag = 'FrictionDataName';
            app.SlipData = uilabel(app.DataPanel);
            app.SlipData.Tag = 'SlipData';
            app.SlipData.Text = {'Load Point '; 'Displacement (μm)'};
            app.FrictionData = uilabel(app.DataPanel);
            app.FrictionData.Tag = 'FrictionData';
            app.FrictionData.Text = 'Friction Data';
            app.TimeDataName = uieditfield(app.DataPanel, 'text');
            app.TimeDataName.Tag = 'TimeDataName';
            app.TimeData = uilabel(app.DataPanel);
            app.TimeData.Tag = 'TimeData';
            app.TimeData.Text = 'Time Data (s)';
            app.P_cDataName = uieditfield(app.DataPanel, 'text');
            app.P_cDataName.Tag = 'P_cDataName';
            app.text59 = uilabel(app.DataPanel);
            app.text59.Tag = 'text59';
            app.text59.Text = 'Normal Stress (MPa)';
            app.VelocityStepPanel = uipanel(app.RASGUI);
            app.VelocityStepPanel.Title = 'Velocity Step Parameters';
            app.VelocityStepPanel.Tag = 'VelocityStepPanel';
            app.StepFrictionValue = uieditfield(app.VelocityStepPanel, 'text');
            app.StepFrictionValue.Tag = 'StepFrictionValue';
            app.InitialVelocityValue = uieditfield(app.VelocityStepPanel, 'text');
            app.InitialVelocityValue.Tag = 'InitialVelocityValue';
            app.text8 = uilabel(app.VelocityStepPanel);
            app.text8.Tag = 'text8';
            app.text8.Text = {'Initial Velocity '; '(μm / s)'};
            app.FinalVelocityValue = uieditfield(app.VelocityStepPanel, 'text');
            app.FinalVelocityValue.Tag = 'FinalVelocityValue';
            app.FinalVelocity = uilabel(app.VelocityStepPanel);
            app.FinalVelocity.Tag = 'FinalVelocity';
            app.FinalVelocity.Text = {'Final Velocity '; '(μm / s)'};
            app.SetVelocityStepButton = uibutton(app.VelocityStepPanel, 'push');
            app.SetVelocityStepButton.ButtonPushedFcn = createCallbackFcn(app, @SetVelocityStepButton_Callback, true);
            app.SetVelocityStepButton.Tag = 'SetVelocityStepButton';
            app.SetVelocityStepButton.Text = 'Set Step Location';
            app.StepSlipValue = uieditfield(app.VelocityStepPanel, 'text');
            app.StepSlipValue.Tag = 'StepSlipValue';
            app.text40 = uilabel(app.VelocityStepPanel);
            app.text40.Tag = 'text40';
            app.text40.Text = 'friction';
            app.text41 = uilabel(app.VelocityStepPanel);
            app.text41.Tag = 'text41';
            app.text41.Text = 'displacement';
            app.FittingPanel = uipanel(app.RASGUI);
            app.FittingPanel.Title = 'Fitting Parameters';
            app.FittingPanel.Tag = 'FittingPanel';
            app.ParameterGuesses = uilabel(app.FittingPanel);
            app.ParameterGuesses.Tag = 'ParameterGuesses';
            app.ParameterGuesses.Text = 'Guesses';
            app.Guess_muValue = uieditfield(app.FittingPanel, 'text');
            app.Guess_muValue.Tag = 'Guess_muValue';
            app.Guess_aValue = uieditfield(app.FittingPanel, 'text');
            app.Guess_aValue.Tag = 'Guess_aValue';
            app.Guess_bValue = uieditfield(app.FittingPanel, 'text');
            app.Guess_bValue.Tag = 'Guess_bValue';
            app.Guess_dcValue = uieditfield(app.FittingPanel, 'text');
            app.Guess_dcValue.Tag = 'Guess_dcValue';
            app.Guess_kValue = uieditfield(app.FittingPanel, 'text');
            app.Guess_kValue.Tag = 'Guess_kValue';
            app.AgingFit_muValue = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_muValue.Tag = 'AgingFit_muValue';
            app.Fit_mu = uilabel(app.FittingPanel);
            app.Fit_mu.Tag = 'Fit_mu';
            app.Fit_mu.Text = 'μ₀';
            app.AgingFit_aValue = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_aValue.Tag = 'AgingFit_aValue';
            app.Fit_a = uilabel(app.FittingPanel);
            app.Fit_a.Tag = 'Fit_a';
            app.Fit_a.Text = 'a';
            app.AgingFit_bValue = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_bValue.Tag = 'AgingFit_bValue';
            app.Fit_b = uilabel(app.FittingPanel);
            app.Fit_b.Tag = 'Fit_b';
            app.Fit_b.Text = 'b₁';
            app.AgingFit_dcValue = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_dcValue.Tag = 'AgingFit_dcValue';
            app.Fit_dc = uilabel(app.FittingPanel);
            app.Fit_dc.Tag = 'Fit_dc';
            app.Fit_dc.Text = 'dc₁';
            app.AgingFit_kValue = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_kValue.Tag = 'AgingFit_kValue';
            app.Fit_k = uilabel(app.FittingPanel);
            app.Fit_k.Tag = 'Fit_k';
            app.Fit_k.Text = {'Stiffness'; '(1 / μm)'};
            app.SlipFit_muValue = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_muValue.Tag = 'SlipFit_muValue';
            app.SlipFit_aValue = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_aValue.Tag = 'SlipFit_aValue';
            app.SlipFit_bValue = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_bValue.Tag = 'SlipFit_bValue';
            app.SlipFit_dcValue = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_dcValue.Tag = 'SlipFit_dcValue';
            app.SlipFit_kValue = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_kValue.Tag = 'SlipFit_kValue';
            app.Guess_b2Value = uieditfield(app.FittingPanel, 'text');
            app.Guess_b2Value.Tag = 'Guess_b2Value';
            app.text52 = uilabel(app.FittingPanel);
            app.text52.Tag = 'text52';
            app.text52.Text = 'b₂';
            app.Guess_dc2Value = uieditfield(app.FittingPanel, 'text');
            app.Guess_dc2Value.Tag = 'Guess_dc2Value';
            app.text53 = uilabel(app.FittingPanel);
            app.text53.Tag = 'text53';
            app.text53.Text = 'dc₂';
            app.AgingRes = uieditfield(app.FittingPanel, 'text');
            app.AgingRes.Tag = 'AgingRes';
            app.AgingFlag = uieditfield(app.FittingPanel, 'text');
            app.AgingFlag.Tag = 'AgingFlag';
            app.SlipRes = uieditfield(app.FittingPanel, 'text');
            app.SlipRes.Tag = 'SlipRes';
            app.SlipFlag = uieditfield(app.FittingPanel, 'text');
            app.SlipFlag.Tag = 'SlipFlag';
            app.text68 = uilabel(app.FittingPanel);
            app.text68.Tag = 'text68';
            app.text68.Text = {'R-squared & '; 'Exit Flag'};
            app.AgingButton = uibutton(app.FittingPanel, 'state');
            app.AgingButton.ValueChangedFcn = createCallbackFcn(app, @AgingButtonValueChanged, true);
            app.AgingButton.Text = 'Aging Fit';
            app.SlipButton = uibutton(app.FittingPanel, 'state');
            app.SlipButton.ValueChangedFcn = createCallbackFcn(app, @SlipButtonValueChanged, true);
            app.SlipButton.Text = 'Slip Fit';
            app.AICLabel = uilabel(app.FittingPanel);
            app.AICLabel.Text = 'AIC';
            app.AgingAIC = uieditfield(app.FittingPanel, 'text');
            app.AgingAIC.Tag = 'AgingRes';
            app.SlipAIC = uieditfield(app.FittingPanel, 'text');
            app.SlipAIC.Tag = 'AgingRes';
            app.AgingFit_muValue_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_muValue_2.Tag = 'AgingFit_muValue';
            app.AgingFit_aValue_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_aValue_2.Tag = 'AgingFit_aValue';
            app.AgingFit_bValue_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_bValue_2.Tag = 'AgingFit_bValue';
            app.AgingFit_dcValue_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_dcValue_2.Tag = 'AgingFit_dcValue';
            app.AgingFit_kValue_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_kValue_2.Tag = 'AgingFit_kValue';
            app.AgingFit_b2Value_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_b2Value_2.Tag = 'AgingFit_b2Value';
            app.AgingFit_dc2Value_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFit_dc2Value_2.Tag = 'AgingFit_dc2Value';
            app.AgingRes_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingRes_2.Tag = 'AgingRes';
            app.AgingFlag_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingFlag_2.Tag = 'AgingFlag';
            app.AgingAIC_2 = uieditfield(app.FittingPanel, 'text');
            app.AgingAIC_2.Tag = 'AgingRes';
            app.SlipFit_muValue_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_muValue_2.Tag = 'SlipFit_muValue';
            app.SlipFit_aValue_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_aValue_2.Tag = 'SlipFit_aValue';
            app.SlipFit_bValue_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_bValue_2.Tag = 'SlipFit_bValue';
            app.SlipFit_dcValue_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_dcValue_2.Tag = 'SlipFit_dcValue';
            app.SlipFit_kValue_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_kValue_2.Tag = 'SlipFit_kValue';
            app.SlipFit_b2Value_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_b2Value_2.Tag = 'SlipFit_b2Value';
            app.SlipFit_dc2Value_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFit_dc2Value_2.Tag = 'SlipFit_dc2Value';
            app.SlipRes_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipRes_2.Tag = 'SlipRes';
            app.SlipFlag_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipFlag_2.Tag = 'SlipFlag';
            app.SlipAIC_2 = uieditfield(app.FittingPanel, 'text');
            app.SlipAIC_2.Tag = 'AgingRes';
            app.TestButton = uibutton(app.FittingPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButton_Callback, true);
            app.TestButton.Tag = 'TestButton';
            app.TestButton.Text = 'Test';
            app.FitButton = uibutton(app.FittingPanel, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButton_Callback, true);
            app.FitButton.Tag = 'FitButton';
            app.FitButton.Text = 'Fit';
            app.WorkspaceName = uieditfield(app.FittingPanel, 'text');
            app.WorkspaceName.Tag = 'WorkspaceName';
            app.text54 = uilabel(app.FittingPanel);
            app.text54.Tag = 'text54';
            app.text54.Text = '.mat Name';
            app.SaveWorkspaceButton = uibutton(app.FittingPanel, 'push');
            app.SaveWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWorkspaceButton_Callback, true);
            app.SaveWorkspaceButton.Tag = 'SaveWorkspaceButton';
            app.SaveWorkspaceButton.Text = 'Save .mat ';
            app.FitTagName = uieditfield(app.FittingPanel, 'text');
            app.FitTagName.Tag = 'FitTagName';
            app.FitTag = uilabel(app.FittingPanel);
            app.FitTag.Tag = 'FitTag';
            app.FitTag.Text = 'Structure Name';
            app.CreateStructureButton = uibutton(app.FittingPanel, 'push');
            app.CreateStructureButton.ButtonPushedFcn = createCallbackFcn(app, @CreateStructureButton_Callback, true);
            app.CreateStructureButton.Tag = 'CreateStructureButton';
            app.CreateStructureButton.Text = 'Create';
            app.SHSPanel = uipanel(app.RASGUI);
            app.SHSPanel.Title = 'Slide-Hold-Slide Parameters';
            app.SHSPanel.Tag = 'SHSPanel';
            app.text45 = uilabel(app.SHSPanel);
            app.text45.Tag = 'text45';
            app.text45.Text = 'displacement';
            app.text44 = uilabel(app.SHSPanel);
            app.text44.Tag = 'text44';
            app.text44.Text = 'friction';
            app.HoldSlipValue = uieditfield(app.SHSPanel, 'text');
            app.HoldSlipValue.Tag = 'HoldSlipValue';
            app.SetHoldButton = uibutton(app.SHSPanel, 'push');
            app.SetHoldButton.ButtonPushedFcn = createCallbackFcn(app, @SetHoldButton_Callback, true);
            app.SetHoldButton.Tag = 'SetHoldButton';
            app.SetHoldButton.Text = 'Set Hold Location';
            app.text43 = uilabel(app.SHSPanel);
            app.text43.Tag = 'text43';
            app.text43.Text = {'Reload Velocity '; '(μm / s)'};
            app.ReloadVelocityValue = uieditfield(app.SHSPanel, 'text');
            app.ReloadVelocityValue.Tag = 'ReloadVelocityValue';
            app.text42 = uilabel(app.SHSPanel);
            app.text42.Tag = 'text42';
            app.text42.Text = {'Load Velocity '; '(μm / s)'};
            app.LoadVelocityValue = uieditfield(app.SHSPanel, 'text');
            app.LoadVelocityValue.Tag = 'LoadVelocityValue';
            app.HoldFrictionValue = uieditfield(app.SHSPanel, 'text');
            app.HoldFrictionValue.Tag = 'HoldFrictionValue';
            app.HoldTime = uieditfield(app.SHSPanel, 'text');
            app.HoldTime.Tag = 'HoldTime';
            app.text46 = uilabel(app.SHSPanel);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Hold Duration (s)';
            app.ListBox = uilistbox(app.RASGUI);
            app.ListBox.Items = {''};
            app.ListBox.Tag = 'ListBox';
            app.ListBox.Value = {};
            app.UpdateListboxButton = uibutton(app.RASGUI, 'push');
            app.UpdateListboxButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListboxButton_Callback, true);
            app.UpdateListboxButton.Tag = 'UpdateListboxButton';
            app.UpdateListboxButton.Text = 'Update Listbox';
            app.EventButtonGroup = uibuttongroup(app.RASGUI);
            app.EventButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EventButtonGroup_SelectionChangedFcn, true);
            app.EventButtonGroup.Title = 'Event Type';
            app.EventButtonGroup.Tag = 'EventButtonGroup';
            app.SHSButton = uiradiobutton(app.EventButtonGroup);
            app.SHSButton.Tag = 'SHSButton';
            app.SHSButton.Text = 'Slide-Hold-Slide';
            app.SHSButton.Value = true;
            app.VelocityStepButton = uiradiobutton(app.EventButtonGroup);
            app.VelocityStepButton.Tag = 'VelocityStepButton';
            app.VelocityStepButton.Text = 'Velocity Step';
            app.WeightPanel = uipanel(app.RASGUI);
            app.WeightPanel.Title = 'Weight Parameters';
            app.WeightPanel.Tag = 'WeightPanel';
            app.text60 = uilabel(app.WeightPanel);
            app.text60.Tag = 'text60';
            app.text60.Text = 'displacement';
            app.text61 = uilabel(app.WeightPanel);
            app.text61.Tag = 'text61';
            app.text61.Text = 'friction';
            app.WeightSlipValue = uieditfield(app.WeightPanel, 'text');
            app.WeightSlipValue.Tag = 'WeightSlipValue';
            app.WeightLocButton = uibutton(app.WeightPanel, 'push');
            app.WeightLocButton.ButtonPushedFcn = createCallbackFcn(app, @WeightLocButton_Callback, true);
            app.WeightLocButton.Tag = 'WeightLocButton';
            app.WeightLocButton.Text = 'Set Weight Location';
            app.WeightFrictionValue = uieditfield(app.WeightPanel, 'text');
            app.WeightFrictionValue.Tag = 'WeightFrictionValue';
            app.text65 = uilabel(app.WeightPanel);
            app.text65.Tag = 'text65';
            app.text65.Text = {'Weight Function'; 'Exponent'};
            app.WeightExponent = uieditfield(app.WeightPanel, 'text');
            app.WeightExponent.Tag = 'WeightExponent';
            app.ClearTestLines = uibutton(app.RASGUI, 'push');
            app.ClearTestLines.ButtonPushedFcn = createCallbackFcn(app, @ClearTestLines_Callback, true);
            app.ClearTestLines.Tag = 'ClearTestLines';
            app.ClearTestLines.Text = 'Clear Test Lines';
            app.ClearFitLines = uibutton(app.RASGUI, 'push');
            app.ClearFitLines.ButtonPushedFcn = createCallbackFcn(app, @ClearFitLines_Callback, true);
            app.ClearFitLines.Tag = 'ClearFitLines';
            app.ClearFitLines.Text = 'Clear Fit Lines';
            app.LocNormStress = uieditfield(app.RASGUI, 'text');
            app.LocNormStress.Tag = 'LocNormStress';
            app.text67 = uilabel(app.RASGUI);
            app.text67.Tag = 'text67';
            app.text67.Text = {'Normal Stress at'; 'Location (MPa)'};
            app.FittingOptions = uibuttongroup(app.RASGUI);
            app.FittingOptions.Title = 'Fitting Options';
            app.WeightButton = uibutton(app.FittingOptions, 'state');
            app.WeightButton.ValueChangedFcn = createCallbackFcn(app, @WeightButtonValueChanged, true);
            app.WeightButton.Text = 'Use Weight Function';
            app.MuButton = uibutton(app.FittingOptions, 'state');
            app.MuButton.ValueChangedFcn = createCallbackFcn(app, @MuButtonValueChanged, true);
            app.MuButton.Text = 'Constrain μ₀';
            app.StiffnessButton = uibutton(app.FittingOptions, 'state');
            app.StiffnessButton.ValueChangedFcn = createCallbackFcn(app, @StiffnessButtonValueChanged, true);
            app.StiffnessButton.Text = 'Constrain Stiffness';
            % Show the figure after all components are created
            app.RASGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RSFit3000_App_AIC_2_State(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RASGUI)

                % Execute the startup function
                runStartupFcn(app, @(app)RSFit3000_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.RASGUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RASGUI)
        end
    end
end